classdef A2GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                      matlab.ui.Figure
        PlayGameButton               matlab.ui.control.Button
        Label_12                     matlab.ui.control.Label
        Label_11                     matlab.ui.control.Label
        Label_10                     matlab.ui.control.Label
        YLabel_4                     matlab.ui.control.Label
        PLabel_2                     matlab.ui.control.Label
        RLabel_2                     matlab.ui.control.Label
        Label_9                      matlab.ui.control.Label
        Label_8                      matlab.ui.control.Label
        Label_7                      matlab.ui.control.Label
        YLabel_3                     matlab.ui.control.Label
        PLabel                       matlab.ui.control.Label
        RLabel                       matlab.ui.control.Label
        JointPositionTeachLabel      matlab.ui.control.Label
        Joint6Slider                 matlab.ui.control.Slider
        Joint6SliderLabel            matlab.ui.control.Label
        Joint5Slider                 matlab.ui.control.Slider
        Joint5SliderLabel            matlab.ui.control.Label
        Joint4Slider                 matlab.ui.control.Slider
        Joint4SliderLabel            matlab.ui.control.Label
        Joint3Slider                 matlab.ui.control.Slider
        Joint3SliderLabel            matlab.ui.control.Label
        Joint2Slider                 matlab.ui.control.Slider
        Joint1Slider                 matlab.ui.control.Slider
        Joint1SliderLabel            matlab.ui.control.Label
        Joint2SliderLabel            matlab.ui.control.Label
        RobotSelectDropDown          matlab.ui.control.DropDown
        RobotSelectDropDownLabel     matlab.ui.control.Label
        RMRCButton                   matlab.ui.control.Button
        Label_6                      matlab.ui.control.Label
        Label_5                      matlab.ui.control.Label
        Label_4                      matlab.ui.control.Label
        UR5EndEffectorPoseLabel      matlab.ui.control.Label
        ZLabel_2                     matlab.ui.control.Label
        YLabel_2                     matlab.ui.control.Label
        XLabel_2                     matlab.ui.control.Label
        FullmovementButton           matlab.ui.control.Button
        PasswordEditField            matlab.ui.control.EditField
        PasswordEditFieldLabel       matlab.ui.control.Label
        HomeRobotButton              matlab.ui.control.Button
        EXITButton                   matlab.ui.control.Button
        DummyanimationButton         matlab.ui.control.Button
        Lamp                         matlab.ui.control.Lamp
        EmergencyStopButton          matlab.ui.control.Button
        YButton_4                    matlab.ui.control.Button
        YButton_3                    matlab.ui.control.Button
        PButton_2                    matlab.ui.control.Button
        PButton                      matlab.ui.control.Button
        RButton_2                    matlab.ui.control.Button
        RButton                      matlab.ui.control.Button
        EndEffectorRotationLabel     matlab.ui.control.Label
        EndEffectorTranslationLabel  matlab.ui.control.Label
        Label_3                      matlab.ui.control.Label
        Label_2                      matlab.ui.control.Label
        Label                        matlab.ui.control.Label
        R900EndEffectorPoseLabel     matlab.ui.control.Label
        ZLabel                       matlab.ui.control.Label
        YLabel                       matlab.ui.control.Label
        XLabel                       matlab.ui.control.Label
        ZButton_2                    matlab.ui.control.Button
        ZButton                      matlab.ui.control.Button
        YButton_2                    matlab.ui.control.Button
        YButton                      matlab.ui.control.Button
        XButton_2                    matlab.ui.control.Button
        XButton                      matlab.ui.control.Button
        plot                         matlab.ui.control.UIAxes
    end


    properties (Access = private)
        % place your class properties here
        R900 %instance of KUKAKR6R900
        UR5s %instance of UR5
        puck %instance of puck
        baseUR5 = transl(0,1.35,0.6) * trotz(pi/2); %base of the UR5
        baseKR6 = transl(0,-1.60,0.5) * trotz(pi/2); %Initial position of KR6 R900
        basePuck = transl(0, 0 , 0.5);
        deltaTranslation = 0.01; %step value for jogging
        deltaRotation = 6; %step value for rotation
        flag = true; % flag check for emergency stop button
        steps = 150; %steps for quintic polynomial trajectory
        password = 'reset'; %emergency stop password
        currentRobot
        teachPositions = zeros(1,6)
        guessUR5 =  [0, 0.6283,0.0000 ,2.5133 ,3.5186 ,0 ]                  %Guess for RMRC UR5
        guessR900 = [0,0.8796 ,0.6283,0.0000,-0.6283,0]                     %Guess for RMRC R900

        % Allocate memory for RMRC calculation
        epsilon = 0.2;                                                     %Threshold for DLS/ Manipulability
        W = diag([1 1 1 0.1 0.1 0.1]);                                     %Weighting Matrix (choose which values are more important for velocity)
        m = zeros(500,1);                                                % Array for Measure of Manipulability
        qMatrix = zeros(500,6);                                          % Array for joint angles
        qdot = zeros(500,6);                                             % Array for joint velocities
        theta = zeros(3,500);                                            % Array for roll-pitch-yaw angles
        x = zeros(3,500);                                                % Array for x-y-z trajectory
    end

    methods (Access = private)

        function PrintFKineR900(app, newPose) %Fkine label print
            xPos = newPose(1, 4);
            yPos = newPose(2, 4);
            zPos = newPose(3, 4);
            app.Label.Text = num2str(xPos);
            app.Label_2.Text = num2str(yPos);
            app.Label_3.Text = num2str(zPos);
        end
        function PrintFKineUR5(app, newPose)
            xPos = newPose(1,4);
            yPos = newPose(2,4);
            zPos = newPose(3,4);
            app.Label_4.Text = num2str(xPos);
            app.Label_5.Text = num2str(yPos);
            app.Label_6.Text = num2str(zPos);
        end
        % function HomeCheck(app, currentPose)
        %     if (currentPose == ~zeros(1,app.KR6.model.n))
        %         app.DummyanimationButton.Enable = false;
        %     else
        %         app.DummyanimationButton.Enable = true;
        %     end
        % end

        function EnvironmentSetup(app)


            axis equal

            %Load Fences

            fence1 = PlaceObject('fenceFinal.ply',[-1.5,1.40,0]);
            hold on
            fence2 = PlaceObject('fenceFinal.ply',[-1.5,0.60,0]);
            hold on
            % fence3 = PlaceObject('fenceFinal.ply',[-1.5,-0.20,0])
            % hold on
            fence4 = PlaceObject('fenceFinal.ply',[-1.5,-1.0,0]);
            hold on
            fence17 = PlaceObject('fenceFinal.ply',[-1.5,-1.8,0]);


            fence5 = PlaceObject('fenceFinal.ply',[1.50,-1.80,0]);
            hold on
            fence6 = PlaceObject('fenceFinal.ply',[1.50,-1.00,0]);
            hold on
            % fence7 = PlaceObject('fenceFinal.ply',[1.50,-0.20,0])
            % hold on
            fence8 = PlaceObject('fenceFinal.ply',[1.50,0.60,0]);
            hold on
            fence18 = PlaceObject('fenceFinal.ply',[1.50,1.40,0]);
            hold on

            fence9 = PlaceObject('fenceFinal.ply',[2.2,-1.2,0]);
            verts = [get(fence9,'Vertices'), ones(size(get(fence9,'Vertices'),1),1)] * trotz(pi/2); %rotating the side of this fence around the z-axis
            verts(:,1) = verts(:,1) * 1; %1 square units in length
            set(fence9,'Vertices',verts(:,1:3))
            hold on

            fence10 = PlaceObject('fenceFinal.ply',[2.2,-0.4,0]);
            verts = [get(fence10,'Vertices'), ones(size(get(fence10,'Vertices'),1),1)] * trotz(pi/2); %rotating the side of this fence around the z-axis
            verts(:,1) = verts(:,1) * 1; %1 square units in length
            set(fence10,'Vertices',verts(:,1:3))
            hold on

            fence11 = PlaceObject('fenceFinal.ply',[2.2,0.4,0]);
            verts = [get(fence11,'Vertices'), ones(size(get(fence11,'Vertices'),1),1)] * trotz(pi/2); %rotating the side of this fence around the z-axis
            verts(:,1) = verts(:,1) * 1; %1 square units in length
            set(fence11,'Vertices',verts(:,1:3))
            hold on

            fence12 = PlaceObject('fenceFinal.ply',[2.2,1.2,0]);
            verts = [get(fence12,'Vertices'), ones(size(get(fence12,'Vertices'),1),1)] * trotz(pi/2); %rotating the side of this fence around the z-axis
            verts(:,1) = verts(:,1) * 1; %1 square units in length
            set(fence12,'Vertices',verts(:,1:3))
            hold on

            fence13 = PlaceObject('fenceFinal.ply',[-2.1,-1.2,0]);
            verts = [get(fence13,'Vertices'), ones(size(get(fence13,'Vertices'),1),1)] * trotz(pi/2); %rotating the side of this fence around the z-axis
            verts(:,1) = verts(:,1) * 1; %1 square units in length
            set(fence13,'Vertices',verts(:,1:3))
            hold on

            fence14 = PlaceObject('fenceFinal.ply',[-2.1,-0.4,0]);
            verts = [get(fence14,'Vertices'), ones(size(get(fence14,'Vertices'),1),1)] * trotz(pi/2); %rotating the side of this fence around the z-axis
            verts(:,1) = verts(:,1) * 1; %1 square units in length
            set(fence14,'Vertices',verts(:,1:3))
            hold on

            fence15 = PlaceObject('fenceFinal.ply',[-2.1,0.4,0]);
            verts = [get(fence15,'Vertices'), ones(size(get(fence15,'Vertices'),1),1)] * trotz(pi/2); %rotating the side of this fence around the z-axis
            verts(:,1) = verts(:,1) * 1; %1 square units in length
            set(fence15,'Vertices',verts(:,1:3))
            hold on

            fence16 = PlaceObject('fenceFinal.ply',[-2.1,1.2,0]);
            verts = [get(fence16,'Vertices'), ones(size(get(fence16,'Vertices'),1),1)] * trotz(pi/2); %rotating the side of this fence around the z-axis
            verts(:,1) = verts(:,1) * 1; %1 square units in length
            set(fence16,'Vertices',verts(:,1:3))
            hold on

            %Load Height Booster for KR6 900
            % heightBooster = PlaceObject('kuka_block.ply',[0,-1.9,0]);
            % hold on

            %Load Barriers on Table
            barrier1 = PlaceObject('barrier.ply',[0,1.35,0.5]);
            hold on
            barrier2 = PlaceObject('barrier.ply',[0.31,1.35,0.5]);
            hold on
            barrier3 = PlaceObject('barrier.ply',[-0.31,1.35,0.5]);
            hold on
            barrier4 = PlaceObject('barrier.ply',[0.50,1.35,0.5]);
            hold on
            barrier5 = PlaceObject('barrier.ply',[-0.50,1.35,0.5]);
            hold on

            barrier6 = PlaceObject('barrier.ply',[0,-1.35,0.5]);
            hold on
            barrier7 = PlaceObject('barrier.ply',[0.31,-1.35,0.5]);
            hold on
            barrier8 = PlaceObject('barrier.ply',[-0.31,-1.35,0.5]);
            hold on
            barrier9 = PlaceObject('barrier.ply',[0.50,-1.35,0.5]);
            hold on
            barrier10 = PlaceObject('barrier.ply',[-0.50,-1.35,0.5]);
            hold on

            barrier11 = PlaceObject('barrier.ply',[0,0.65,0.5]);
            verts = [get(barrier11,'Vertices'), ones(size(get(barrier11,'Vertices'),1),1)] * trotz(pi/2); %rotating the side of this fence around the z-axis
            verts(:,1) = verts(:,1) * 1; %1 square units in length
            set(barrier11,'Vertices',verts(:,1:3))
            hold on

            barrier12 = PlaceObject('barrier.ply',[0.3,0.65,0.5]);
            verts = [get(barrier12,'Vertices'), ones(size(get(barrier12,'Vertices'),1),1)] * trotz(pi/2); %rotating the side of this fence around the z-axis
            verts(:,1) = verts(:,1) * 1; %1 square units in length
            set(barrier12,'Vertices',verts(:,1:3))
            hold on

            barrier13 = PlaceObject('barrier.ply',[0.6,0.65,0.5]);
            verts = [get(barrier13,'Vertices'), ones(size(get(barrier13,'Vertices'),1),1)] * trotz(pi/2); %rotating the side of this fence around the z-axis
            verts(:,1) = verts(:,1) * 1; %1 square units in length
            set(barrier13,'Vertices',verts(:,1:3))
            hold on

            barrier14 = PlaceObject('barrier.ply',[0.9,0.65,0.5]);
            verts = [get(barrier14,'Vertices'), ones(size(get(barrier14,'Vertices'),1),1)] * trotz(pi/2); %rotating the side of this fence around the z-axis
            verts(:,1) = verts(:,1) * 1; %1 square units in length
            set(barrier14,'Vertices',verts(:,1:3))
            hold on

            barrier15 = PlaceObject('barrier.ply',[-0.3,0.65,0.5]);
            verts = [get(barrier15,'Vertices'), ones(size(get(barrier15,'Vertices'),1),1)] * trotz(pi/2); %rotating the side of this fence around the z-axis
            verts(:,1) = verts(:,1) * 1; %1 square units in length
            set(barrier15,'Vertices',verts(:,1:3))
            hold on

            barrier16 = PlaceObject('barrier.ply',[-0.6,0.65,0.5]);
            verts = [get(barrier16,'Vertices'), ones(size(get(barrier16,'Vertices'),1),1)] * trotz(pi/2); %rotating the side of this fence around the z-axis
            verts(:,1) = verts(:,1) * 1; %1 square units in length
            set(barrier16,'Vertices',verts(:,1:3))
            hold on

            barrier17 = PlaceObject('barrier.ply',[-0.9,0.65,0.5]);
            verts = [get(barrier17,'Vertices'), ones(size(get(barrier17,'Vertices'),1),1)] * trotz(pi/2); %rotating the side of this fence around the z-axis
            verts(:,1) = verts(:,1) * 1; %1 square units in length
            set(barrier17,'Vertices',verts(:,1:3))
            hold on

            barrier18 = PlaceObject('barrier.ply',[-1.2,0.65,0.5]);
            verts = [get(barrier18,'Vertices'), ones(size(get(barrier18,'Vertices'),1),1)] * trotz(pi/2); %rotating the side of this fence around the z-axis
            verts(:,1) = verts(:,1) * 1; %1 square units in length
            set(barrier18,'Vertices',verts(:,1:3))
            hold on

            barrier19 = PlaceObject('barrier.ply',[1.2,0.65,0.5]);
            verts = [get(barrier19,'Vertices'), ones(size(get(barrier19,'Vertices'),1),1)] * trotz(pi/2); %rotating the side of this fence around the z-axis
            verts(:,1) = verts(:,1) * 1; %1 square units in length
            set(barrier19,'Vertices',verts(:,1:3))
            hold on

            barrier20 = PlaceObject('barrier.ply',[0,-0.65,0.5]);
            verts = [get(barrier20,'Vertices'), ones(size(get(barrier20,'Vertices'),1),1)] * trotz(pi/2); %rotating the side of this fence around the z-axis
            verts(:,1) = verts(:,1) * 1; %1 square units in length
            set(barrier20,'Vertices',verts(:,1:3))
            hold on

            barrier21 = PlaceObject('barrier.ply',[0.3,-0.65,0.5]);
            verts = [get(barrier21,'Vertices'), ones(size(get(barrier21,'Vertices'),1),1)] * trotz(pi/2); %rotating the side of this fence around the z-axis
            verts(:,1) = verts(:,1) * 1; %1 square units in length
            set(barrier21,'Vertices',verts(:,1:3))
            hold on

            barrier22 = PlaceObject('barrier.ply',[0.6,-0.65,0.5]);
            verts = [get(barrier22,'Vertices'), ones(size(get(barrier22,'Vertices'),1),1)] * trotz(pi/2); %rotating the side of this fence around the z-axis
            verts(:,1) = verts(:,1) * 1; %1 square units in length
            set(barrier22,'Vertices',verts(:,1:3))
            hold on

            barrier23 = PlaceObject('barrier.ply',[0.9,-0.65,0.5]);
            verts = [get(barrier23,'Vertices'), ones(size(get(barrier23,'Vertices'),1),1)] * trotz(pi/2); %rotating the side of this fence around the z-axis
            verts(:,1) = verts(:,1) * 1; %1 square units in length
            set(barrier23,'Vertices',verts(:,1:3))
            hold on

            barrier24 = PlaceObject('barrier.ply',[-0.3,-0.65,0.5]);
            verts = [get(barrier24,'Vertices'), ones(size(get(barrier24,'Vertices'),1),1)] * trotz(pi/2); %rotating the side of this fence around the z-axis
            verts(:,1) = verts(:,1) * 1; %1 square units in length
            set(barrier24,'Vertices',verts(:,1:3))
            hold on

            barrier25 = PlaceObject('barrier.ply',[-0.6,-0.65,0.5]);
            verts = [get(barrier25,'Vertices'), ones(size(get(barrier25,'Vertices'),1),1)] * trotz(pi/2); %rotating the side of this fence around the z-axis
            verts(:,1) = verts(:,1) * 1; %1 square units in length
            set(barrier25,'Vertices',verts(:,1:3))
            hold on

            barrier26 = PlaceObject('barrier.ply',[-0.9,-0.65,0.5]);
            verts = [get(barrier26,'Vertices'), ones(size(get(barrier26,'Vertices'),1),1)] * trotz(pi/2); %rotating the side of this fence around the z-axis
            verts(:,1) = verts(:,1) * 1; %1 square units in length
            set(barrier26,'Vertices',verts(:,1:3))
            hold on

            barrier27 = PlaceObject('barrier.ply',[-1.2,-0.65,0.5]);
            verts = [get(barrier27,'Vertices'), ones(size(get(barrier27,'Vertices'),1),1)] * trotz(pi/2); %rotating the side of this fence around the z-axis
            verts(:,1) = verts(:,1) * 1; %1 square units in length
            set(barrier27,'Vertices',verts(:,1:3))
            hold on

            barrier28 = PlaceObject('barrier.ply',[1.2,-0.65,0.5]);
            verts = [get(barrier28,'Vertices'), ones(size(get(barrier28,'Vertices'),1),1)] * trotz(pi/2); %rotating the side of this fence around the z-axis
            verts(:,1) = verts(:,1) * 1; %1 square units in length
            set(barrier28,'Vertices',verts(:,1:3))
            hold on

            % %Load Blocker
            % blocker1 = PlaceObject('blocker.ply',[0,0,0.5])
            % hold on


            %Load Tables
            table1 = PlaceObject('tableBrown2.1x1.4x0.5m.ply',[0,0,0]);
            hold on
            table2 = PlaceObject('tableBrown2.1x1.4x0.5m.ply',[0,-1,0]);
            hold on
            table3 = PlaceObject('tableBrown2.1x1.4x0.5m.ply',[0,1,0]);
            hold on


            %Load Fire Extinguisher
            fireExtinguisher = PlaceObject('fireExtinguisher.ply',[1.3,-2.0,0]);
            hold on


            %Emergency Stop Button
            Emergency_StopButton = PlaceObject('emergencyStopButton.ply',[0.9,0.9,0.5]);
            hold on
            Emergency_StopButton = PlaceObject('emergencyStopButton.ply',[-0.9,-0.9,0.5]);
            hold on


            % K = KUKAKR6R900(transl(0,-1.35,0.5)) * trotz(pi/2); %load in KR6900
            % Robot.model.teach(Robot.model.getpos)
            %
            % qStart = zeros(1,Robot.model.n); %Initialise robot


        end

        function RMRCMove(app, robot, pos1, pos2, roll, pitch, yaw)
            r = robot;
            t = 10;                                                            %Time
            deltaT = 0.02;                                                     %Control frequency
            stepsRMRC = t/deltaT;                                              %Steps derived from Time and control frequency
            %% Set up 3D trajectory (This is a straight line example, Two points:)
            %x1 = transl(0.65, -0.65, 0.2);
            x1 = pos1;
            %x2 = transl(0.65, 0.65, 0.2);                                               % Point 2
            x2 = pos2;
            %Straight line trajectory between x1 and x2 (you can create other
            %trajectories
            s = linspace(0, 1, stepsRMRC);                                                  % Linearly spaced scalar
            for i = 1:stepsRMRC %x1 to x2
                % Interpolate between x1 and x2 using s(i) as a scaling factor
                app.x(:, i) = transl(x1 * (1 - s(i)) + x2 * s(i));
                % Set the orientation angles to constants or calculate as needed
                app.theta(1, i) = roll; % Roll angle
                app.theta(2, i) = pitch; % Pitch angle
                app.theta(3, i) = yaw; % Yaw angle
            end
            % Plot
            %plot3(app.x(1,:),app.x(2,:),app.x(3,:),'k.','LineWidth',1)

            %% Calculation of the first transform
            %Use ikcone/ikine to obtain the correct joint orientation (elbow up/down)

            T = [rpy2r(app.theta(1,1),app.theta(2,1),app.theta(3,1))  app.x(:,1);zeros(1,3) 1];          % Create transformation of first point and angle
            q0 = app.teachPositions;                                                                % Initialize guess positions
            % Depending on robot, initial guess positions are different
            % to get elbow up start
            if(app.currentRobot == app.R900)
                q0 = app.guessR900;
            elseif(app.currentRobot == app.UR5s)
                q0 = app.guessUR5;
            end
            %q0 = zeros(1,r.model.n);
            app.qMatrix(1,:) = r.model.ikcon(T,q0);                                            % Solve joint angles to achieve first waypoint

            %% RMRC calculation
            for i = 1:stepsRMRC-1
                % UPDATE: fkine function now returns an SE3 object. To obtain the
                % Transform Matrix, access the variable in the object 'T' with '.T'.
                T = r.model.fkine(app.qMatrix(i,:)).T;                                           % Get forward transformation at current joint state
                deltaX = app.x(:,i+1) - T(1:3,4);                                         	% Get position error from next waypoint
                Rd = rpy2r(app.theta(1,i+1),app.theta(2,i+1),app.theta(3,i+1));                     % Get next RPY angles, convert to rotation matrix
                Ra = T(1:3,1:3);                                                        % Current end-effector rotation matrix
                Rdot = (1/deltaT)*(Rd - Ra);                                                % Calculate rotation matrix error
                S = Rdot*Ra';                                                           % Skew symmetric!
                linear_velocity = (1/deltaT)*deltaX;
                angular_velocity = [S(3,2);S(1,3);S(2,1)];                              % Check the structure of Skew Symmetric matrix!!
                xdot = app.W*[linear_velocity;angular_velocity];                          	% Calculate end-effector velocity to reach next waypoint.
                J = r.model.jacob0(app.qMatrix(i,:));                                       % Get Jacobian at current joint state
                app.m(i) = sqrt(det(J*J'));                                                 % Yoshikawa's Measure of Manipulability
                %Attenuating damping
                if app.m(i) < app.epsilon                                                       % If manipulability is less than given threshold
                    % Damping increased
                    lambda = (1 - app.m(i)/app.epsilon)*5E-2;
                else
                    % No damping
                    lambda = 0;
                end
                %This is done because you cannot normally invert the Jacobian
                invJ = inv(J'*J + lambda *eye(6))*J';                                   % DLS Psuedo Inverse Jacobian
                app.qdot(i,:) = (invJ*xdot)';                                               % Solve the RMRC equation (you may need to transpose the vector)
                for j = 1:6                                                             % Loop through joints 1 to 6
                    if app.qMatrix(i,j) + deltaT*app.qdot(i,j) < r.model.qlim(j,1)                     % If next joint angle is lower than joint limit...
                        app.qdot(i,j) = 0; % Stop the motor
                    elseif app.qMatrix(i,j) + deltaT*app.qdot(i,j) > r.model.qlim(j,2)                 % If next joint angle is greater than joint limit ...
                        app.qdot(i,j) = 0; % Stop the motor
                    end
                end
                app.qMatrix(i+1,:) = app.qMatrix(i,:) + deltaT*app.qdot(i,:);                         	% Update next joint state based on joint velocities
                % Animate
                r.model.animate(app.qMatrix(i,:));
                drawnow();
                % ESTOP
                if(~app.flag)
                    pause();
                end
            end
        end

        function PrintRPYR900(app, newPose)
            rpyValues = newPose(1:3 , 1:3); % Rotation Matrix
            roll = atan2(rpyValues(3,2), rpyValues(3,3));
            pitch = atan2(-rpyValues(3,1), sqrt(rpyValues(3,2)^2 + rpyValues(3,3)^2));
            yaw = atan2(rpyValues(2,1), rpyValues(1,1));
            app.Label_7.Text = num2str(rad2deg(roll));
            app.Label_8.Text = num2str(rad2deg(pitch));
            app.Label_9.Text = num2str(rad2deg(yaw));
        end
        function PrintRPYUR5(app, newPose)
            rpyValues = newPose(1:3 , 1:3); % Rotation Matrix
            roll = atan2(rpyValues(3,2), rpyValues(3,3));
            pitch = atan2(-rpyValues(3,1), sqrt(rpyValues(3,2)^2 + rpyValues(3,3)^2));
            yaw = atan2(rpyValues(2,1), rpyValues(1,1));
            app.Label_10.Text = num2str(rad2deg(roll));
            app.Label_11.Text = num2str(rad2deg(pitch));
            app.Label_12.Text = num2str(rad2deg(yaw));
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function init(app, varargin)
            % Code to run once GUI is loaded successfully
            ax = gca;
            app.EnvironmentSetup;
            app.R900 = KUKAKR6R900(app.baseKR6);
            app.UR5s = UR5(app.baseUR5);
            app.currentRobot = app.R900;

            app.puck = RobotPuck(1,app.basePuck);
            %app.puck.puckModel{1}.base = app.basePuck;

            hold on
            %app.KR6.model.base = transl([-0.5,0,0]);
            %app.R900.model.animate(zeros(1,app.R900.model.n));
            drawnow();

            % Delete the green floor surface (need to count backwards
            % because once it is deleted the array is compressed)
            for i = size(ax.Children,1):-1:1
                if strcmp(ax.Children(i).Type,'surface')
                    delete(ax.Children(i));
                end
            end


        end

        % Button pushed function: XButton_2
        function XButton_2Pushed(app, event)
            % Move the end effector in the - X direction
            currentPose = app.currentRobot.model.fkine(app.currentRobot.model.getpos()).T;
            newPose = currentPose * transl(-app.deltaTranslation, 0, 0);
            q = app.currentRobot.model.ikine(newPose);
            app.currentRobot.model.animate(q);
            if(app.currentRobot == app.R900)
                app.PrintFKineR900(newPose);
            else
                app.PrintFKineUR5(newPose);
            end
        end

        % Button pushed function: XButton
        function XButtonPushed(app, event)
            % Move the end effector in the + X direction
            currentPose = app.currentRobot.model.fkine(app.currentRobot.model.getpos()).T;
            newPose = currentPose * transl(app.deltaTranslation, 0, 0);
            q = app.currentRobot.model.ikine(newPose);
            app.currentRobot.model.animate(q);
            if(app.currentRobot == app.R900)
                app.PrintFKineR900(newPose);
            else
                app.PrintFKineUR5(newPose);
            end
        end

        % Button pushed function: YButton_2
        function YButton_2Pushed(app, event)
            % Move the end effector in the - Y direction
            currentPose = app.currentRobot.model.fkine(app.currentRobot.model.getpos()).T;
            newPose = currentPose * transl(0, -app.deltaTranslation, 0);
            q = app.currentRobot.model.ikine(newPose);
            app.currentRobot.model.animate(q);
            if(app.currentRobot == app.R900)
                app.PrintFKineR900(newPose);
                app.PrintRPYR900(newPose);
            else
                app.PrintFKineUR5(newPose);
                app.PrintRPYUR5(newPose);
            end
        end

        % Button pushed function: YButton
        function YButtonPushed(app, event)
            % Move the end effector in the  + Y direction
            currentPose = app.currentRobot.model.fkine(app.currentRobot.model.getpos()).T;
            newPose = currentPose * transl(0, app.deltaTranslation, 0);
            q = app.currentRobot.model.ikine(newPose);
            app.currentRobot.model.animate(q);
            if(app.currentRobot == app.R900)
                app.PrintFKineR900(newPose);
                app.PrintRPYR900(newPose);
            else
                app.PrintFKineUR5(newPose);
                app.PrintRPYUR5(newPose);
            end
        end

        % Button pushed function: ZButton_2
        function ZButton_2Pushed(app, event)
            % Move the end effector in the - Z direction
            currentPose = app.currentRobot.model.fkine(app.currentRobot.model.getpos()).T;
            newPose = currentPose * transl(0, 0, -app.deltaTranslation);
            q = app.currentRobot.model.ikine(newPose);
            app.currentRobot.model.animate(q);
            if(app.currentRobot == app.R900)
                app.PrintFKineR900(newPose);
                app.PrintRPYR900(newPose);
            else
                app.PrintFKineUR5(newPose);
                app.PrintRPYUR5(newPose);
            end
        end

        % Button pushed function: ZButton
        function ZButtonPushed(app, event)
            % Move the end effector in the + Z direction
            currentPose = app.currentRobot.model.fkine(app.currentRobot.model.getpos()).T;
            newPose = currentPose * transl(0, 0, app.deltaTranslation);
            q = app.currentRobot.model.ikine(newPose);
            app.currentRobot.model.animate(q);
            if(app.currentRobot == app.R900)
                app.PrintFKineR900(newPose);
                app.PrintRPYR900(newPose);
            else
                app.PrintFKineUR5(newPose);
                app.PrintRPYUR5(newPose);
            end
        end

        % Button pushed function: RButton
        function RButtonPushed(app, event)
            currentPose = app.currentRobot.model.fkine(app.currentRobot.model.getpos()).T;
            rollAngle = deg2rad(app.deltaRotation);
            rollRotation = trotx(-rollAngle);
            newPose = currentPose * rollRotation;
            q = app.currentRobot.model.ikine(newPose);
            app.currentRobot.model.animate(q);
            if(app.currentRobot == app.R900)
                app.PrintFKineR900(newPose);
                app.PrintRPYR900(newPose);
            else
                app.PrintFKineUR5(newPose);
                app.PrintRPYUR5(newPose);
            end
        end

        % Button pushed function: RButton_2
        function RButton_2Pushed(app, event)
            currentPose = app.currentRobot.model.fkine(app.currentRobot.model.getpos()).T;
            rollAngle = deg2rad(app.deltaRotation);
            rollRotation = trotx(rollAngle);
            newPose = currentPose * rollRotation;
            q = app.currentRobot.model.ikine(newPose);
            app.currentRobot.model.animate(q);
            if(app.currentRobot == app.R900)
                app.PrintFKineR900(newPose);
                app.PrintRPYR900(newPose);
            else
                app.PrintFKineUR5(newPose);
                app.PrintRPYUR5(newPose);
            end
        end

        % Button pushed function: PButton
        function PButtonPushed(app, event)
            currentPose = app.currentRobot.model.fkine(app.currentRobot.model.getpos()).T;
            rollAngle = deg2rad(app.deltaRotation);
            rollRotation = troty(-rollAngle);
            newPose = currentPose * rollRotation;
            q = app.currentRobot.model.ikine(newPose);
            app.currentRobot.model.animate(q);
            if(app.currentRobot == app.R900)
                app.PrintFKineR900(newPose);
                app.PrintRPYR900(newPose);
            else
                app.PrintFKineUR5(newPose);
                app.PrintRPYUR5(newPose);
            end
        end

        % Button pushed function: PButton_2
        function PButton_2Pushed(app, event)
            currentPose = app.currentRobot.model.fkine(app.currentRobot.model.getpos()).T;
            rollAngle = deg2rad(app.deltaRotation);
            rollRotation = troty(rollAngle);
            newPose = currentPose * rollRotation;
            q = app.currentRobot.model.ikine(newPose);
            app.currentRobot.model.animate(q);
            if(app.currentRobot == app.R900)
                app.PrintFKineR900(newPose);
                app.PrintRPYR900(newPose);
            else
                app.PrintFKineUR5(newPose);
                app.PrintRPYUR5(newPose);
            end
        end

        % Button pushed function: YButton_3
        function YButton_3Pushed(app, event)
            currentPose = app.currentRobot.model.fkine(app.currentRobot.model.getpos()).T;
            rollAngle = deg2rad(app.deltaRotation);
            rollRotation = trotz(-rollAngle);
            newPose = currentPose * rollRotation;
            q = app.currentRobot.model.ikine(newPose);
            app.currentRobot.model.animate(q);
            if(app.currentRobot == app.R900)
                app.PrintFKineR900(newPose);
                app.PrintRPYR900(newPose);
            else
                app.PrintFKineUR5(newPose);
                app.PrintRPYUR5(newPose);
            end
        end

        % Button pushed function: YButton_4
        function YButton_4Pushed(app, event)
            currentPose = app.currentRobot.model.fkine(app.currentRobot.model.getpos()).T;
            rollAngle = deg2rad(app.deltaRotation);
            rollRotation = trotz(rollAngle);
            newPose = currentPose * rollRotation;
            q = app.currentRobot.model.ikine(newPose);
            app.currentRobot.model.animate(q);
            if(app.currentRobot == app.R900)
                app.PrintFKineR900(newPose);
                app.PrintRPYR900(newPose);
            else
                app.PrintFKineUR5(newPose);
                app.PrintRPYUR5(newPose);
            end
        end

        % Button pushed function: DummyanimationButton
        function DummyanimationButtonPushed(app, event)
            %Simple dummy movement
            while(app.flag)
                q1 = zeros(1,app.R900.model.n); %random joints to move between
                q2 = deg2rad([45, -20, 45, -30, -70,90]);

                firstMove = jtraj(q1,q2,app.steps);
                for i = 1:app.steps
                    app.R900.model.animate(firstMove(i,:))
                    drawnow();
                    app.PrintFKineR900(app.R900.model.fkineUTS(app.R900.model.getpos));
                    if(~app.flag)
                        pause();
                    end
                end
                %reversing the movement
                backMove = jtraj(q2,q1,app.steps);
                for i = 1:app.steps
                    app.R900.model.animate(backMove(i,:));
                    drawnow();
                    app.PrintFKineR900(app.R900.model.fkineUTS(app.R900.model.getpos));
                    if(~app.flag) %emergency button switches flag to pause, still can use jogging
                        pause();
                    end
                end
                % if(app.flag)
                %     break;
                % end
            end
        end

        % Button pushed function: EmergencyStopButton
        function EmergencyStopButtonPushed(app, event)
            %emergency button changes flag, no need for if statement
            %keeping just in case

            app.flag = ~app.flag;
            if(app.flag)
                % app.Lamp.Color = 'g';
                % app.DummyanimationButton.Enable = true;
                % app.HomeRobotButton.Enable = true;
            else %if pushed, change lamp colour and disable animations.
                app.Lamp.Color = 'r';
                app.DummyanimationButton.Enable = false;
                app.HomeRobotButton.Enable = false;
                app.RMRCButton.Enable = false;
                app.FullmovementButton.Enable = false;
                app.PlayGameButton.Enable = false;
            end
            app.EmergencyStopButton.Enable = false;
            % app.HomeCheck(app.KR6.model.getpos);


        end

        % Button pushed function: EXITButton
        function EXITButtonPushed(app, event)
            app.delete;
        end

        % Button pushed function: HomeRobotButton
        function HomeRobotButtonPushed(app, event)
            %returns to zero position after reset.

            q1R900 = app.R900.model.getpos;
            q2R900 = zeros(1,app.R900.model.n);
            q1UR5 = app.UR5s.model.getpos;
            q2UR5 = zeros(1,app.UR5s.model.n);
            returnMatrixR900 = jtraj(q1R900,q2R900,app.steps);
            returnMatrixUR5 = jtraj(q1UR5,q2UR5,app.steps);
            for i = 1:app.steps
                app.UR5s.model.animate(returnMatrixUR5(i,:));
                app.R900.model.animate(returnMatrixR900(i,:));
                drawnow();
                app.PrintFKineR900(app.R900.model.fkineUTS(app.R900.model.getpos));
                app.PrintFKineUR5(app.UR5s.model.fkineUTS(app.UR5s.model.getpos));
            end
            %enables movement after homing is complete
            app.DummyanimationButton.Enable = true;
            app.RMRCButton.Enable = true;
            app.FullmovementButton.Enable = true;
        end

        % Value changed function: PasswordEditField
        function PasswordEditFieldValueChanged(app, event)
            %password required after pressing emergency stop
            input = app.PasswordEditField.Value;
            %correct password enables home position
            if strcmp(input, app.password)
                app.Lamp.Color = 'g';
                app.flag = true;
                %app.DummyanimationButton.Enable = true;
                app.HomeRobotButton.Enable = true;
                app.EmergencyStopButton.Enable = true;
                app.PasswordEditField.Value = '';
                app.RMRCButton.Enable = true;
                app.DummyanimationButton.Enable = true;
                app.FullmovementButton.Enable = true;
                app.PlayGameButton.Enable = true;
            else

                app.DummyanimationButton.Enable = false;
                app.EmergencyStopButton.Enable = false;
                app.PasswordEditField.Value = '';
                app.RMRCButton.Enable = false;
                app.DummyanimationButton.Enable = false;
                app.FullmovementButton.Enable = false;
                app.PlayGameButton.Enable = false;
            end

        end

        % Button pushed function: FullmovementButton
        function FullmovementButtonPushed(app, event)


            %%
            U_base = [0,1.5,0.5];
            K_base = [0,-1.60,0.5];
            P_base = [0, 0 , 0.5];

            U_guess_pos = [ 0   -1.0347    2.2842   -2.7925   -1.5708         0];
            K_guess_pos = [0    0.8712    0.3142   -0.6680         0         0];

            % K = KUKAKR6R900(transl(K_base));
            % U = UR5(transl(U_base));

            %puck = RobotPuck(1);
            %app.puck.puckModel{1}.base = transl(P_base);

            % Position the endeffector of the robots to the starting position

            xPositions = linspace(0.5,-0.5,3);
            %app.puck.puckModel{1}.base = transl(P_base);
            animate(app.puck.puckModel{1},0);
            next_position_U = app.UR5s.model.ikcon( SE3(U_base).T*SE3(0,-0.5,0).T* trotx(pi)*trotz(pi/2), U_guess_pos);
            app.UR5s.model.animate(next_position_U);
            drawnow();


            next_position_K = app.R900.model.ikcon( SE3(K_base).T * SE3(0,0.5,0).T * trotx(pi)*trotz(pi/2), K_guess_pos );
            app.R900.model.animate(next_position_K);
            drawnow();


            %% Moving the UR5


            for i = 1:size(xPositions,2)

                pos_U = app.UR5s.model.ikcon( (SE3(U_base).T * SE3(xPositions(i), -0.5 , 0).T * trotx(pi)), app.UR5s.model.getpos);
                traj = jtraj(app.UR5s.model.getpos(), pos_U, 100);

                % Create a traj for the puck from its current position to the contact point
                currentPuck = app.puck.puckModel{1}.base.t';
                goalPuck = SE3(U_base).T * SE3(xPositions(i), -0.5 , 0).T * trotx(pi);
                goalPuck = goalPuck(1:3,4)';

                pos_Px = linspace(currentPuck(1),goalPuck(1),100);
                pos_Py = linspace(currentPuck(2),goalPuck(2),100);

                % ESTOP
                if(~app.flag)
                    pause();
                end

                for y = 1:size(traj,1)
                    app.puck.puckModel{1}.base = transl( pos_Px(y),pos_Py(y), 0.5);
                    app.UR5s.model.animate(traj(y,:));
                    drawnow();
                    animate(app.puck.puckModel{1},0);
                    %E STOP
                    if(~app.flag)
                        pause();
                    end
                end


                % Moving the Kuka

                pos_K = app.R900.model.ikcon( (SE3(K_base).T * SE3(xPositions(i), 0.5 , 0).T * trotx(pi)), app.R900.model.getpos);
                traj = jtraj(app.R900.model.getpos(), pos_K, 100);

                currentPuck = app.puck.puckModel{1}.base.t';
                goalPuck = SE3(K_base).T * SE3(xPositions(i), 0.5 , 0).T * trotx(pi);
                goalPuck = goalPuck(1:3,4)';

                pos_Px = linspace(currentPuck(1),goalPuck(1),100);
                pos_Py = linspace(currentPuck(2),goalPuck(2),100);


                for y = 1:size(traj,1)
                    app.puck.puckModel{1}.base = transl( pos_Px(y),pos_Py(y), 0.5);
                    animate(app.puck.puckModel{1},0);
                    app.R900.model.animate(traj(y,:));
                    drawnow();
                    animate(app.puck.puckModel{1},0);

                    %E STOP
                    if(~app.flag)
                        pause();
                    end
                end


            end
            %%
            next_position_U = app.UR5s.model.ikcon( SE3(U_base).T*SE3(0,-0.5,0).T* trotx(pi)*trotz(pi/2), U_guess_pos);
            app.UR5s.model.animate(next_position_U);
            drawnow();


            next_position_K = app.R900.model.ikcon( SE3(K_base).T * SE3(0,0.5,0).T * trotx(pi)*trotz(pi/2), K_guess_pos );
            app.R900.model.animate(next_position_K);
            drawnow();
            app.puck.puckModel{1}.base = transl(P_base);
            animate(app.puck.puckModel{1},0);
        end

        % Button pushed function: RMRCButton
        function RMRCButtonPushed(app, event)
            if(app.currentRobot == app.R900)
                app.RMRCMove(app.currentRobot, transl(-0.5, -1, 0.6), transl(0.5, -1, 0.6), -pi/2 , -pi/2 , 0)
            elseif(app.currentRobot == app.UR5s)
                app.RMRCMove(app.currentRobot,transl(-0.5, 0.75, 0.6), transl(0.5, 0.75, 0.6), pi/2 , 0 , 0)
            end

        end

        % Value changed function: RobotSelectDropDown
        function RobotSelectDropDownValueChanged(app, event)
            value = app.RobotSelectDropDown.Value;
            if (strcmp(value, "UR5") == 1)
                app.currentRobot = app.UR5s;
            elseif (strcmp(value, "R900") == 1)
                app.currentRobot = app.R900;
            end
        end

        % Value changed function: Joint1Slider
        function Joint1SliderValueChanged(app, event)
            j = app.Joint1Slider.Value;
            currentJoints = app.currentRobot.model.getpos;
            currentJoints(1) = deg2rad(j);
            newJoints = currentJoints;
            app.currentRobot.model.animate(newJoints);
            drawnow();
            newPose = app.currentRobot.model.fkine(newJoints).T;
            if(app.currentRobot == app.R900)
                app.PrintFKineR900(newPose);
                app.PrintRPYR900(newPose);
            else
                app.PrintFKineUR5(newPose);
                app.PrintRPYUR5(newPose);
            end
        end

        % Value changing function: Joint1Slider
        function Joint1SliderValueChanging(app, event)
            changingJ = event.Value;
            currentJoints = app.currentRobot.model.getpos;
            currentJoints(1) = deg2rad(changingJ);
            newJoints = currentJoints;
            app.currentRobot.model.animate(newJoints);
            newPose = app.currentRobot.model.fkine(newJoints).T;
            if(app.currentRobot == app.R900)
                app.PrintFKineR900(newPose);
                app.PrintRPYR900(newPose);
            else
                app.PrintFKineUR5(newPose);
                app.PrintRPYUR5(newPose);
            end
        end

        % Value changed function: Joint2Slider
        function Joint2SliderValueChanged(app, event)
            j = app.Joint2Slider.Value;
            currentJoints = app.currentRobot.model.getpos;
            currentJoints(2) = deg2rad(j);
            newJoints = currentJoints;
            app.currentRobot.model.animate(newJoints);
            drawnow();
            newPose = app.currentRobot.model.fkine(newJoints).T;
            if(app.currentRobot == app.R900)
                app.PrintFKineR900(newPose);
                app.PrintRPYR900(newPose);
            else
                app.PrintFKineUR5(newPose);
                app.PrintRPYUR5(newPose);
            end
        end

        % Value changing function: Joint2Slider
        function Joint2SliderValueChanging(app, event)
            changingJ = event.Value;
            currentJoints = app.currentRobot.model.getpos;
            currentJoints(2) = deg2rad(changingJ);
            newJoints = currentJoints;
            app.currentRobot.model.animate(newJoints);
            newPose = app.currentRobot.model.fkine(newJoints).T;
            if(app.currentRobot == app.R900)
                app.PrintFKineR900(newPose);
                app.PrintRPYR900(newPose);
            else
                app.PrintFKineUR5(newPose);
                app.PrintRPYUR5(newPose);
            end
        end

        % Value changed function: Joint3Slider
        function Joint3SliderValueChanged(app, event)
            j = app.Joint3Slider.Value;
            currentJoints = app.currentRobot.model.getpos;
            currentJoints(3) = deg2rad(j);
            newJoints = currentJoints;
            app.currentRobot.model.animate(newJoints);
            drawnow();
            newPose = app.currentRobot.model.fkine(newJoints).T;
            if(app.currentRobot == app.R900)
                app.PrintFKineR900(newPose);
                app.PrintRPYR900(newPose);
            else
                app.PrintFKineUR5(newPose);
                app.PrintRPYUR5(newPose);
            end
        end

        % Value changing function: Joint3Slider
        function Joint3SliderValueChanging(app, event)
            changingJ = event.Value;
            currentJoints = app.currentRobot.model.getpos;
            currentJoints(3) = deg2rad(changingJ);
            newJoints = currentJoints;
            app.currentRobot.model.animate(newJoints);
            newPose = app.currentRobot.model.fkine(newJoints).T;
            if(app.currentRobot == app.R900)
                app.PrintFKineR900(newPose);
                app.PrintRPYR900(newPose);
            else
                app.PrintFKineUR5(newPose);
                app.PrintRPYUR5(newPose);
            end
        end

        % Value changed function: Joint4Slider
        function Joint4SliderValueChanged(app, event)
            j = app.Joint4Slider.Value;
            currentJoints = app.currentRobot.model.getpos;
            currentJoints(4) = deg2rad(j);
            newJoints = currentJoints;
            app.currentRobot.model.animate(newJoints);
            drawnow();
            newPose = app.currentRobot.model.fkine(newJoints).T;
            if(app.currentRobot == app.R900)
                app.PrintFKineR900(newPose);
                app.PrintRPYR900(newPose);
            else
                app.PrintFKineUR5(newPose);
                app.PrintRPYUR5(newPose);
            end
        end

        % Value changing function: Joint4Slider
        function Joint4SliderValueChanging(app, event)
            changingJ = event.Value;
            currentJoints = app.currentRobot.model.getpos;
            currentJoints(4) = deg2rad(changingJ);
            newJoints = currentJoints;
            app.currentRobot.model.animate(newJoints);
            newPose = app.currentRobot.model.fkine(newJoints).T;
            if(app.currentRobot == app.R900)
                app.PrintFKineR900(newPose);
                app.PrintRPYR900(newPose);
            else
                app.PrintFKineUR5(newPose);
                app.PrintRPYUR5(newPose);
            end
        end

        % Value changed function: Joint5Slider
        function Joint5SliderValueChanged(app, event)
            j = app.Joint5Slider.Value;
            currentJoints = app.currentRobot.model.getpos;
            currentJoints(5) = deg2rad(j);
            newJoints = currentJoints;
            app.currentRobot.model.animate(newJoints);
            drawnow();
            newPose = app.currentRobot.model.fkine(newJoints).T;
            if(app.currentRobot == app.R900)
                app.PrintFKineR900(newPose);
                app.PrintRPYR900(newPose);
            else
                app.PrintFKineUR5(newPose);
                app.PrintRPYUR5(newPose);
            end
        end

        % Value changing function: Joint5Slider
        function Joint5SliderValueChanging(app, event)
            changingJ = event.Value;
            currentJoints = app.currentRobot.model.getpos;
            currentJoints(5) = deg2rad(changingJ);
            newJoints = currentJoints;
            app.currentRobot.model.animate(newJoints);
            newPose = app.currentRobot.model.fkine(newJoints).T;
            if(app.currentRobot == app.R900)
                app.PrintFKineR900(newPose);
                app.PrintRPYR900(newPose);
            else
                app.PrintFKineUR5(newPose);
                app.PrintRPYUR5(newPose);
            end
        end

        % Value changed function: Joint6Slider
        function Joint6SliderValueChanged(app, event)
            j = app.Joint6Slider.Value;
            currentJoints = app.currentRobot.model.getpos;
            currentJoints(6) = deg2rad(j);
            newJoints = currentJoints;
            app.currentRobot.model.animate(newJoints);
            drawnow();
            newPose = app.currentRobot.model.fkine(newJoints).T;
            if(app.currentRobot == app.R900)
                app.PrintFKineR900(newPose);
                app.PrintRPYR900(newPose);
            else
                app.PrintFKineUR5(newPose);
                app.PrintRPYUR5(newPose);
            end
        end

        % Value changing function: Joint6Slider
        function Joint6SliderValueChanging(app, event)
            changingJ = event.Value;
            currentJoints = app.currentRobot.model.getpos;
            currentJoints(6) = deg2rad(changingJ);
            newJoints = currentJoints;
            app.currentRobot.model.animate(newJoints);
            newPose = app.currentRobot.model.fkine(newJoints).T;
            if(app.currentRobot == app.R900)
                app.PrintFKineR900(newPose);
                app.PrintRPYR900(newPose);
            else
                app.PrintFKineUR5(newPose);
                app.PrintRPYUR5(newPose);
            end
        end

        % Button pushed function: PlayGameButton
        function PlayGameButtonPushed(app, event)
            %% Collision
            % Puck radius is 0.08 meters Or 0.171 m

            %% Making a sides of the game

            inplay = 0;
            blockRadi = 0.2;


            %app.puck = RobotPuck(1,transl(0,-1,0.55));
            % puck.puckModel{1}.base = transl(0,0.0,0.55) ;
            animate(app.puck.puckModel{1},0);
            pause(0.01);
            drawnow();

            % Turn the following to a function file

            % Plane Left:
            % Define the plane using the point and normal
            plane1Origin = -0.5100;
            p1Normal = [-1 0 0];
            p1Point =  [ plane1Origin 0 0];
            %plot the plane
            [Y1, Z1] = meshgrid(-1.5:0.1:1.5, -1.5:0.1:1.5);
            X1 = repmat(plane1Origin , size(Y1,1), size(Z1,1));
            % surf(X1,Y1,Z1);


            % Plane Right:
            %define the plane using the point and normal
            plane2Origin = 0.51;
            p2Normal = [-1 0 0];
            p2Point =  [ plane2Origin 0 0];
            % Plot the plane
            [Y2, Z2] = meshgrid(-1.5:0.1:1.5, -1.5:0.1:1.5);
            X2 = repmat(plane2Origin , size(Y2,1), size(Z2,1));
            % surf(X2,Y2,Z2);

            % Kuka Plane
            KukaPlane = -1.21;
            KukaNormal = [0 -1 0];
            KukaPoint =  [ 0 KukaPlane 0];
            %plot the plane
            [X3, Z3] = meshgrid(-1.29:0.1:1.29, -1.29:0.1:1.29);
            Y3 = repmat((KukaPlane - 0.08) , size(X3,1), size(Z3,1));
            % surf(X3,Y3,Z3);

            % UR5 Plane
            UR5Plane =  1.21;
            UR5Normal = [0 -1 0];
            UR5Point =  [ 0 UR5Plane 0];
            %plot the plane
            [X4, Z4] = meshgrid(-1.29:0.1:1.29, -1.29:0.1:1.29);
            Y4 = repmat(UR5Plane , size(X4,1), size(Z4,1));
            % surf(X4,Y4,Z4);

            % UR5 EndEffector
            UR5EE = app.UR5s.model.fkine(app.UR5s.model.getpos).t';
            UR5EEPlane =  UR5EE(2) - 0.08; % make sure to have the offset to account for the pucks radius
            UR5EENormal = [0 -1 0];
            UR5EEPoint =  [ 0 UR5EEPlane 0];
            %plot the plane and make it small enough for the blocker

            [X5, Z5] = meshgrid(linspace(UR5EE(1) - blockRadi,UR5EE(1) + blockRadi,10),linspace(0.5,0.6,10));
            Y5 = repmat((UR5EEPlane+0.08) , size(X5,1), size(Z5,1));
            %surf(X5,Y5,Z5);

            % Kuka EndEffector
            KukaEE = app.R900.model.fkine(app.R900.model.getpos).t';
            KukaEEPlane =  KukaEE(2) + 0.08; % make sure to have the offset to account for the pucks radius
            KukaEENormal = [0 -1 0];
            KukaEEPoint =  [ 0 KukaEEPlane 0];
            %plot the plane and make it small enough for the blocker

            [X6, Z6] = meshgrid(linspace(KukaEE(1)-blockRadi,KukaEE(1) + blockRadi,10),linspace(0.5,0.6,10));
            Y6 = repmat((KukaEEPlane - 0.08), size(X6,1), size(Z6,1));
            %surf(X6,Y6,Z6);

            % Set up the puck


            axis equal

            %% Bouncing Puck off the wall after a Collision
            KukaGoal = 0;
            UR5Goal = 0;
            gameStart = 1;
            guessJ =   [0,0.8796 ,0.6283,0.0000,-0.6283,0];
            while gameStart == 1
                gameStart = 0;
                inplay = 1;
                movement = transl(0,0.02,0);

                % Define the two numbers you want to choose between
                number1 = -1;
                number2 = 1;

                % Generate a random number between 0 and 1
                random_number = rand;

                % Use the random number to select one of the two numbers
                if random_number < 0.5
                    direction = trotz(0);
                else
                    direction = trotz(pi);
                end

                app.puck.puckModel{1}.base =   transl(0,0,0.55) *  trotz((randi([-45,45])),"deg")*direction;
                animate(app.puck.puckModel{1},0);
                pause(0.01);
                drawnow();
                Puckcenter = app.puck.puckModel{1}.base.t';

                if KukaGoal == 3
                    disp("KUKA WINS GAME")
                    inplay = 0;
                    gameStart = 0;
                end
                if UR5Goal == 3
                    disp("UR5 WINS GAME")
                    inplay = 0;
                    gameStart = 0;
                end

                while(inplay == 1)


                    app.puck.puckModel{1}.base = app.puck.puckModel{1}.base.T * movement ;
                    animate(app.puck.puckModel{1},0);
                    pause(0.01);
                    drawnow();
                    Puckcenter_P = Puckcenter;
                    Puckcenter = app.puck.puckModel{1}.base.t';

                    currentPose = app.UR5s.model.fkine(app.UR5s.model.getpos()).T;
                    newPose = transl(Puckcenter(1), 0.75, 0.55) *troty(pi);
                    %q = app.RMRCMove(Robot,currentPose,newPose,-pi/2 , -pi/2 , 0);
                     q = app.UR5s.model.ikcon(newPose,guessJ);
                    app.UR5s.model.animate(q);

                    currentPose1 = app.R900.model.fkine(app.R900.model.getpos()).T;
                    newPose1 = transl(Puckcenter(1), -1, 0.55) *troty(pi);
                    %q1 = app.RMRCMove(Robot2, currentPose1, newPose1, pi/2 , 0 , 0);
                    q1 = app.R900.model.ikcon(newPose1,guessJ);
                    app.R900.model.animate(q1);

                    %E STOP
                    if(~app.flag)
                        pause();
                    end
                    % Check Collision
                    [intersectionPoints1,check1] = LinePlaneIntersection(p1Normal,p1Point,Puckcenter_P,Puckcenter);
                    [intersectionPoints2,check2] = LinePlaneIntersection(p2Normal,p2Point,Puckcenter_P,Puckcenter);
                    [intersectionPoints3,check3] = LinePlaneIntersection(KukaNormal,KukaPoint,Puckcenter_P,Puckcenter);
                    [intersectionPoints4,check4] = LinePlaneIntersection(UR5Normal,UR5Point,Puckcenter_P,Puckcenter);
                    [intersectionPoints5,check5] = LinePlaneIntersection(UR5EENormal,UR5EEPlane,Puckcenter_P,Puckcenter);
                    [intersectionPoints6,check6] = LinePlaneIntersection(KukaEENormal,KukaEEPlane,Puckcenter_P,Puckcenter);

                    % What to do if a COllision is detected
                    if check1 == 1
                        plot3(intersectionPoints1(1),intersectionPoints1(2),intersectionPoints1(3) ,'k*','MarkerSize',5);
                        disp(" Left Wall Hit");

                        PuckVector = (Puckcenter - Puckcenter_P)/norm(Puckcenter - Puckcenter_P);
                        LeftWallVector = ([-0.51,-1.5,0.55]-[-0.51, 1.5, 0.55])/norm([-0.51,-1.5,0.55]-[-0.51, 1.5, 0.55]);
                        dot_product = dot(PuckVector, LeftWallVector);
                        angle_of_entry_rad = acos(dot_product);
                        app.puck.puckModel{1}.base = app.puck.puckModel{1}.base.T * trotz(2*angle_of_entry_rad) * movement ;
                        rad2deg(angle_of_entry_rad)

                        animate(app.puck.puckModel{1},0);
                        pause(0.01);
                        drawnow();

                        Puckcenter = app.puck.puckModel{1}.base.t';

                    end

                    if check2 == 1
                        plot3(intersectionPoints2(1),intersectionPoints2(2),intersectionPoints2(3) ,'k*','MarkerSize',5);
                        disp(" Right Wall Hit");


                        PuckVector = (Puckcenter - Puckcenter_P)/norm(Puckcenter - Puckcenter_P);
                        RightWallVector = ([0.51,-1.5,0.55]-[0.51, 1.5, 0.55])/norm([0.51,-1.5,0.55]-[0.51, 1.5, 0.55]);

                        dot_product = dot(PuckVector, RightWallVector);
                        angle_of_entry_rad = acos(dot_product);
                        app.puck.puckModel{1}.base = app.puck.puckModel{1}.base.T * trotz(-2*angle_of_entry_rad) * movement ;
                        animate(app.puck.puckModel{1},0);
                        rad2deg(angle_of_entry_rad)

                        drawnow();

                        Puckcenter = app.puck.puckModel{1}.base.t';
                    end

                    if check3 == 1
                        plot3(intersectionPoints3(1),intersectionPoints3(2),intersectionPoints3(3) ,'k*','MarkerSize',5);
                        disp(" GOAL UR5");
                        inplay = 0;
                    end

                    if check4 == 1
                        plot3(intersectionPoints4(1),intersectionPoints4(2),intersectionPoints4(3) ,'k*','MarkerSize',5);
                        disp(" GOAL KuKA");
                        inplay = 0;
                    end
                    % End Effector Collision check
                    % The Logic:
                    %   1. Have a plane that is in line with the end effector position
                    %   2. Wait for a collision with the plane
                    %   3. Measure the distance from the puck to the EE
                    %   4. If Distance is smaller then the Radi of the block = Collision.
                    %   Bounce the puck back
                    %   5. if no Colision then the puck will hit the goal

                    % 1. find the end effector position and use the y value to create a Plane
                    % along the x axis.

                    % When a collision is detected. find the distance between the end effector and the
                    % pucks center. if the y distance from the puck to the EE is less then the distance
                    % of the block then the pluck has been blocked.
                    % UR5 collision Check
                    if check5 == 1
                        % Check for the distance from the puck to the end effector Only compare
                        % the y values nothing else is need
                        disp("Puck has hit the EE Plane of UR5");
                        puckCoordinates = app.puck.puckModel{1}.base.t' ;
                        UR5EE = app.UR5s.model.fkine(app.UR5s.model.getpos).t';

                        distanceDif =   (puckCoordinates(1)) - (UR5EE(1));
                        disp("Distance to EE is:");
                        disp(distanceDif);
                        if (-blockRadi < distanceDif) && (distanceDif< blockRadi) % the puck has collided with the blocker. bounce the puck back
                            PuckVector = (Puckcenter - Puckcenter_P)/norm(Puckcenter - Puckcenter_P);
                            UR5EEVector = ([-0.6, UR5EE(2), 0.55]-[0.6, UR5EE(2), 0.55])/norm([-0.6, UR5EE(2), 0.55]-[0.6, UR5EE(2), 0.55]);

                            dot_product = dot(PuckVector, UR5EEVector);
                            angle_of_entry_rad = acos(dot_product);
                            app.puck.puckModel{1}.base = app.puck.puckModel{1}.base.T * trotz(2*angle_of_entry_rad) * trotz(randi([-10,10]),"deg") * movement ;
                            animate(app.puck.puckModel{1},0);
                            animate(app.puck.puckModel{1},0);
                            drawnow();

                            Puckcenter = app.puck.puckModel{1}.base.t';
                        end
                        % if the distanceDif is more than 1 then we just move on since

                    end
                    % Kuka collision Check
                    if check6 == 1
                        % Check for the distance from the puck to the end effector Only compare
                        % the y values nothing else is need
                        disp("Puck has hit the EEPlane of KUKA");
                        puckCoordinates = app.puck.puckModel{1}.base.t' ;
                        KukaEE = app.R900.model.fkine(app.R900.model.getpos).t';

                        distanceDif = (puckCoordinates(1)) - (KukaEE(1));
                        disp("Distance to EE is:");
                        disp(distanceDif);
                        if (-blockRadi < distanceDif) && (distanceDif< blockRadi) % the puck has collided with the blocker. bounce the puck back

                            PuckVector = (Puckcenter - Puckcenter_P)/norm(Puckcenter - Puckcenter_P);
                            KukaEEVector = ([-0.6, KukaEE(2), 0.55]-[0.6, KukaEE(2), 0.55])/norm([-0.6, KukaEE(2), 0.55]-[0.6, KukaEE(2), 0.55]);
                            dot_product = dot(PuckVector, KukaEEVector);
                            angle_of_entry_rad = acos(dot_product);
                            app.puck.puckModel{1}.base = app.puck.puckModel{1}.base.T * trotz(-2*angle_of_entry_rad) * trotz(randi([-10,10]),"deg")* movement ;
                            animate(app.puck.puckModel{1},0);

                            animate(app.puck.puckModel{1},0);
                            drawnow();
                            Puckcenter = app.puck.puckModel{1}.base.t';
                        end
                        % if the distanceDif is more than 1 then we just move on since
                    end
                end
            end
            %% Bounce with angles

            % % 1. Nomarlize both vectors
            % PuckVector = (Puckcenter - Puckcenter_P)/norm(Puckcenter - Puckcenter_P);
            % LeftWallVector = ([-0.51,-1.5,0.55]-[-0.51, 1.5, 0.55])/norm([-0.51,-1.5,0.55]-[-0.51, 1.5, 0.55]);
            % dot_product = dot(PuckVector, LeftWallVector);
            % angle_of_entry_rad = acos(dot_product);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'TestRobotPlot_V2';
            app.figure1.Tag = 'figure1';
            app.plot = uiaxes(app.figure1);
            xlabel(app.plot, 'X')
            ylabel(app.plot, 'Y')
            app.plot.Tag = 'axes1';
            app.XButton = uibutton(app.figure1, 'push');
            app.XButton.ButtonPushedFcn = createCallbackFcn(app, @XButtonPushed, true);
            app.XButton.Text = '+X';
            app.XButton_2 = uibutton(app.figure1, 'push');
            app.XButton_2.ButtonPushedFcn = createCallbackFcn(app, @XButton_2Pushed, true);
            app.XButton_2.Text = '-X';
            app.YButton = uibutton(app.figure1, 'push');
            app.YButton.ButtonPushedFcn = createCallbackFcn(app, @YButtonPushed, true);
            app.YButton.Text = '+ Y';
            app.YButton_2 = uibutton(app.figure1, 'push');
            app.YButton_2.ButtonPushedFcn = createCallbackFcn(app, @YButton_2Pushed, true);
            app.YButton_2.Text = '- Y';
            app.ZButton = uibutton(app.figure1, 'push');
            app.ZButton.ButtonPushedFcn = createCallbackFcn(app, @ZButtonPushed, true);
            app.ZButton.Text = '+ Z';
            app.ZButton_2 = uibutton(app.figure1, 'push');
            app.ZButton_2.ButtonPushedFcn = createCallbackFcn(app, @ZButton_2Pushed, true);
            app.ZButton_2.Text = '- Z';
            app.XLabel = uilabel(app.figure1);
            app.XLabel.Text = 'X:';
            app.YLabel = uilabel(app.figure1);
            app.YLabel.Text = 'Y:';
            app.ZLabel = uilabel(app.figure1);
            app.ZLabel.Text = 'Z:';
            app.R900EndEffectorPoseLabel = uilabel(app.figure1);
            app.R900EndEffectorPoseLabel.Text = 'R900 End Effector Pose:';
            app.Label = uilabel(app.figure1);
            app.Label.Text = '';
            app.Label_2 = uilabel(app.figure1);
            app.Label_2.Text = '';
            app.Label_3 = uilabel(app.figure1);
            app.Label_3.Text = '';
            app.EndEffectorTranslationLabel = uilabel(app.figure1);
            app.EndEffectorTranslationLabel.Text = 'End Effector Translation';
            app.EndEffectorRotationLabel = uilabel(app.figure1);
            app.EndEffectorRotationLabel.Text = 'End Effector Rotation';
            app.RButton = uibutton(app.figure1, 'push');
            app.RButton.ButtonPushedFcn = createCallbackFcn(app, @RButtonPushed, true);
            app.RButton.Text = '- R';
            app.RButton_2 = uibutton(app.figure1, 'push');
            app.RButton_2.ButtonPushedFcn = createCallbackFcn(app, @RButton_2Pushed, true);
            app.RButton_2.Text = '+ R';
            app.PButton = uibutton(app.figure1, 'push');
            app.PButton.ButtonPushedFcn = createCallbackFcn(app, @PButtonPushed, true);
            app.PButton.Text = '- P';
            app.PButton_2 = uibutton(app.figure1, 'push');
            app.PButton_2.ButtonPushedFcn = createCallbackFcn(app, @PButton_2Pushed, true);
            app.PButton_2.Text = '+ P';
            app.YButton_3 = uibutton(app.figure1, 'push');
            app.YButton_3.ButtonPushedFcn = createCallbackFcn(app, @YButton_3Pushed, true);
            app.YButton_3.Text = '- Y';
            app.YButton_4 = uibutton(app.figure1, 'push');
            app.YButton_4.ButtonPushedFcn = createCallbackFcn(app, @YButton_4Pushed, true);
            app.YButton_4.Text = '+ Y';
            app.EmergencyStopButton = uibutton(app.figure1, 'push');
            app.EmergencyStopButton.ButtonPushedFcn = createCallbackFcn(app, @EmergencyStopButtonPushed, true);
            app.EmergencyStopButton.Text = 'Emergency Stop';
            app.Lamp = uilamp(app.figure1);
            app.DummyanimationButton = uibutton(app.figure1, 'push');
            app.DummyanimationButton.ButtonPushedFcn = createCallbackFcn(app, @DummyanimationButtonPushed, true);
            app.DummyanimationButton.Text = 'Dummy animation';
            app.EXITButton = uibutton(app.figure1, 'push');
            app.EXITButton.ButtonPushedFcn = createCallbackFcn(app, @EXITButtonPushed, true);
            app.EXITButton.Text = 'EXIT';
            app.HomeRobotButton = uibutton(app.figure1, 'push');
            app.HomeRobotButton.ButtonPushedFcn = createCallbackFcn(app, @HomeRobotButtonPushed, true);
            app.HomeRobotButton.Text = 'Home Robot';
            app.PasswordEditFieldLabel = uilabel(app.figure1);
            app.PasswordEditFieldLabel.Text = 'Password';
            app.PasswordEditField = uieditfield(app.figure1, 'text');
            app.PasswordEditField.ValueChangedFcn = createCallbackFcn(app, @PasswordEditFieldValueChanged, true);
            app.FullmovementButton = uibutton(app.figure1, 'push');
            app.FullmovementButton.ButtonPushedFcn = createCallbackFcn(app, @FullmovementButtonPushed, true);
            app.FullmovementButton.Text = 'Full movement';
            app.XLabel_2 = uilabel(app.figure1);
            app.XLabel_2.Text = 'X:';
            app.YLabel_2 = uilabel(app.figure1);
            app.YLabel_2.Text = 'Y:';
            app.ZLabel_2 = uilabel(app.figure1);
            app.ZLabel_2.Text = 'Z:';
            app.UR5EndEffectorPoseLabel = uilabel(app.figure1);
            app.UR5EndEffectorPoseLabel.Text = 'UR5 End Effector Pose:';
            app.Label_4 = uilabel(app.figure1);
            app.Label_4.Text = '';
            app.Label_5 = uilabel(app.figure1);
            app.Label_5.Text = '';
            app.Label_6 = uilabel(app.figure1);
            app.Label_6.Text = '';
            app.RMRCButton = uibutton(app.figure1, 'push');
            app.RMRCButton.ButtonPushedFcn = createCallbackFcn(app, @RMRCButtonPushed, true);
            app.RMRCButton.Text = 'RMRC';
            app.RobotSelectDropDownLabel = uilabel(app.figure1);
            app.RobotSelectDropDownLabel.Text = 'Robot Select';
            app.RobotSelectDropDown = uidropdown(app.figure1);
            app.RobotSelectDropDown.Items = {'UR5', 'R900'};
            app.RobotSelectDropDown.ValueChangedFcn = createCallbackFcn(app, @RobotSelectDropDownValueChanged, true);
            app.RobotSelectDropDown.Value = 'R900';
            app.Joint2SliderLabel = uilabel(app.figure1);
            app.Joint2SliderLabel.Text = 'Joint 2';
            app.Joint1SliderLabel = uilabel(app.figure1);
            app.Joint1SliderLabel.Text = 'Joint 1';
            app.Joint1Slider = uislider(app.figure1);
            app.Joint1Slider.Limits = [-360 360];
            app.Joint1Slider.ValueChangedFcn = createCallbackFcn(app, @Joint1SliderValueChanged, true);
            app.Joint1Slider.ValueChangingFcn = createCallbackFcn(app, @Joint1SliderValueChanging, true);
            app.Joint2Slider = uislider(app.figure1);
            app.Joint2Slider.Limits = [-360 360];
            app.Joint2Slider.ValueChangedFcn = createCallbackFcn(app, @Joint2SliderValueChanged, true);
            app.Joint2Slider.ValueChangingFcn = createCallbackFcn(app, @Joint2SliderValueChanging, true);
            app.Joint3SliderLabel = uilabel(app.figure1);
            app.Joint3SliderLabel.Text = 'Joint 3';
            app.Joint3Slider = uislider(app.figure1);
            app.Joint3Slider.Limits = [-360 360];
            app.Joint3Slider.ValueChangedFcn = createCallbackFcn(app, @Joint3SliderValueChanged, true);
            app.Joint3Slider.ValueChangingFcn = createCallbackFcn(app, @Joint3SliderValueChanging, true);
            app.Joint4SliderLabel = uilabel(app.figure1);
            app.Joint4SliderLabel.Text = 'Joint 4';
            app.Joint4Slider = uislider(app.figure1);
            app.Joint4Slider.Limits = [-360 360];
            app.Joint4Slider.ValueChangedFcn = createCallbackFcn(app, @Joint4SliderValueChanged, true);
            app.Joint4Slider.ValueChangingFcn = createCallbackFcn(app, @Joint4SliderValueChanging, true);
            app.Joint5SliderLabel = uilabel(app.figure1);
            app.Joint5SliderLabel.Text = 'Joint 5';
            app.Joint5Slider = uislider(app.figure1);
            app.Joint5Slider.Limits = [-360 360];
            app.Joint5Slider.ValueChangedFcn = createCallbackFcn(app, @Joint5SliderValueChanged, true);
            app.Joint5Slider.ValueChangingFcn = createCallbackFcn(app, @Joint5SliderValueChanging, true);
            app.Joint6SliderLabel = uilabel(app.figure1);
            app.Joint6SliderLabel.Text = 'Joint 6';
            app.Joint6Slider = uislider(app.figure1);
            app.Joint6Slider.Limits = [-360 360];
            app.Joint6Slider.ValueChangedFcn = createCallbackFcn(app, @Joint6SliderValueChanged, true);
            app.Joint6Slider.ValueChangingFcn = createCallbackFcn(app, @Joint6SliderValueChanging, true);
            app.JointPositionTeachLabel = uilabel(app.figure1);
            app.JointPositionTeachLabel.Text = 'Joint Position Teach';
            app.RLabel = uilabel(app.figure1);
            app.RLabel.Text = 'R:';
            app.PLabel = uilabel(app.figure1);
            app.PLabel.Text = 'P:';
            app.YLabel_3 = uilabel(app.figure1);
            app.YLabel_3.Text = 'Y:';
            app.Label_7 = uilabel(app.figure1);
            app.Label_7.Text = '';
            app.Label_8 = uilabel(app.figure1);
            app.Label_8.Text = '';
            app.Label_9 = uilabel(app.figure1);
            app.Label_9.Text = '';
            app.RLabel_2 = uilabel(app.figure1);
            app.RLabel_2.Text = 'R:';
            app.PLabel_2 = uilabel(app.figure1);
            app.PLabel_2.Text = 'P:';
            app.YLabel_4 = uilabel(app.figure1);
            app.YLabel_4.Text = 'Y:';
            app.Label_10 = uilabel(app.figure1);
            app.Label_10.Text = '';
            app.Label_11 = uilabel(app.figure1);
            app.Label_11.Text = '';
            app.Label_12 = uilabel(app.figure1);
            app.Label_12.Text = '';
            app.PlayGameButton = uibutton(app.figure1, 'push');
            app.PlayGameButton.ButtonPushedFcn = createCallbackFcn(app, @PlayGameButtonPushed, true);
            app.PlayGameButton.Text = 'Play Game';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = A2GUI(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)init(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end