classdef A2GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                      matlab.ui.Figure
        PasswordEditField            matlab.ui.control.EditField
        PasswordEditFieldLabel       matlab.ui.control.Label
        HomeRobotButton              matlab.ui.control.Button
        EXITButton                   matlab.ui.control.Button
        DummyanimationButton         matlab.ui.control.Button
        Lamp                         matlab.ui.control.Lamp
        EmergencyStopButton          matlab.ui.control.Button
        YButton_4                    matlab.ui.control.Button
        YButton_3                    matlab.ui.control.Button
        PButton_2                    matlab.ui.control.Button
        PButton                      matlab.ui.control.Button
        RButton_2                    matlab.ui.control.Button
        RButton                      matlab.ui.control.Button
        EndEffectorRotationLabel     matlab.ui.control.Label
        EndEffectorTranslationLabel  matlab.ui.control.Label
        Label_3                      matlab.ui.control.Label
        Label_2                      matlab.ui.control.Label
        Label                        matlab.ui.control.Label
        EndEffectorGlobalPoseLabel   matlab.ui.control.Label
        ZLabel                       matlab.ui.control.Label
        YLabel                       matlab.ui.control.Label
        XLabel                       matlab.ui.control.Label
        ZButton_2                    matlab.ui.control.Button
        ZButton                      matlab.ui.control.Button
        YButton_2                    matlab.ui.control.Button
        YButton                      matlab.ui.control.Button
        XButton_2                    matlab.ui.control.Button
        XButton                      matlab.ui.control.Button
        plot                         matlab.ui.control.UIAxes
    end


    properties (Access = private)
        % place your class properties here
        KR6 %instance of KUKAKR6R900
        deltaTranslation = 0.01; %step value for jogging
        deltaRotation = 6; %step value for rotation
        flag = true; % flag check for emergency stop button
        steps = 150; %steps for quintic polynomial trajectory
        password = 'reset'; %emergency stop password
    end

    methods (Access = private)

        function PrintFKine(app, newPose) %Fkine label print
            xPos = newPose(1, 4);
            yPos = newPose(2, 4);
            zPos = newPose(3, 4);
            app.Label.Text = num2str(xPos);
            app.Label_2.Text = num2str(yPos);
            app.Label_3.Text = num2str(zPos);
        end
        
        
        % function HomeCheck(app, currentPose)
        %     if (currentPose == ~zeros(1,app.KR6.model.n))
        %         app.DummyanimationButton.Enable = false;
        %     else
        %         app.DummyanimationButton.Enable = true;
        %     end
        % end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function init(app, varargin)
            % Code to run once GUI is loaded successfully
            ax = gca;


            app.KR6 = KUKAKR6R900;
            %app.KR6.model.base = transl([-0.5,0,0]);
            app.KR6.model.animate(zeros(1,app.KR6.model.n));
            drawnow();

            % Delete the green floor surface (need to count backwards
            % because once it is deleted the array is compressed)
            for i = size(ax.Children,1):-1:1
                if strcmp(ax.Children(i).Type,'surface')
                    delete(ax.Children(i));
                end
            end


        end

        % Button pushed function: XButton_2
        function XButton_2Pushed(app, event)
            % Move the end effector in the - X direction
            currentPose = app.KR6.model.fkine(app.KR6.model.getpos()).T;
            newPose = currentPose * transl(-app.deltaTranslation, 0, 0);
            q = app.KR6.model.ikine(newPose);
            app.KR6.model.animate(q);
            app.PrintFKine(newPose);
        end

        % Button pushed function: XButton
        function XButtonPushed(app, event)
            % Move the end effector in the + X direction
            currentPose = app.KR6.model.fkine(app.KR6.model.getpos()).T;
            newPose = currentPose * transl(app.deltaTranslation, 0, 0);
            q = app.KR6.model.ikine(newPose);
            app.KR6.model.animate(q);
            app.PrintFKine(newPose);
        end

        % Button pushed function: YButton_2
        function YButton_2Pushed(app, event)
            % Move the end effector in the - Y direction
            currentPose = app.KR6.model.fkine(app.KR6.model.getpos()).T;
            newPose = currentPose * transl(0, -app.deltaTranslation, 0);
            q = app.KR6.model.ikine(newPose);
            app.KR6.model.animate(q);
            app.PrintFKine(newPose);
        end

        % Button pushed function: YButton
        function YButtonPushed(app, event)
            % Move the end effector in the  + Y direction
            currentPose = app.KR6.model.fkine(app.KR6.model.getpos()).T;
            newPose = currentPose * transl(0, app.deltaTranslation, 0);
            q = app.KR6.model.ikine(newPose);
            app.KR6.model.animate(q);
            app.PrintFKine(newPose);
        end

        % Button pushed function: ZButton_2
        function ZButton_2Pushed(app, event)
            % Move the end effector in the - Z direction
            currentPose = app.KR6.model.fkine(app.KR6.model.getpos()).T;
            newPose = currentPose * transl(0, 0, -app.deltaTranslation);
            q = app.KR6.model.ikine(newPose);
            app.KR6.model.animate(q);
            app.PrintFKine(newPose);
        end

        % Button pushed function: ZButton
        function ZButtonPushed(app, event)
            % Move the end effector in the + Z direction
            currentPose = app.KR6.model.fkine(app.KR6.model.getpos()).T;
            newPose = currentPose * transl(0, 0, app.deltaTranslation);
            q = app.KR6.model.ikine(newPose);
            app.KR6.model.animate(q);
            app.PrintFKine(newPose);
        end

        % Button pushed function: RButton
        function RButtonPushed(app, event)
            currentPose = app.KR6.model.fkine(app.KR6.model.getpos()).T;
            rollAngle = deg2rad(app.deltaRotation);
            rollRotation = trotx(-rollAngle);
            newPose = currentPose * rollRotation;
            q = app.KR6.model.ikine(newPose);
            app.KR6.model.animate(q);
        end

        % Button pushed function: RButton_2
        function RButton_2Pushed(app, event)
            currentPose = app.KR6.model.fkine(app.KR6.model.getpos()).T;
            rollAngle = deg2rad(app.deltaRotation);
            rollRotation = trotx(rollAngle);
            newPose = currentPose * rollRotation;
            q = app.KR6.model.ikine(newPose);
            app.KR6.model.animate(q);
        end

        % Button pushed function: PButton
        function PButtonPushed(app, event)
            currentPose = app.KR6.model.fkine(app.KR6.model.getpos()).T;
            rollAngle = deg2rad(app.deltaRotation);
            rollRotation = troty(-rollAngle);
            newPose = currentPose * rollRotation;
            q = app.KR6.model.ikine(newPose);
            app.KR6.model.animate(q);
        end

        % Button pushed function: PButton_2
        function PButton_2Pushed(app, event)
            currentPose = app.KR6.model.fkine(app.KR6.model.getpos()).T;
            rollAngle = deg2rad(app.deltaRotation);
            rollRotation = troty(rollAngle);
            newPose = currentPose * rollRotation;
            q = app.KR6.model.ikine(newPose);
            app.KR6.model.animate(q);
        end

        % Button pushed function: YButton_3
        function YButton_3Pushed(app, event)
            currentPose = app.KR6.model.fkine(app.KR6.model.getpos()).T;
            rollAngle = deg2rad(app.deltaRotation);
            rollRotation = trotz(-rollAngle);
            newPose = currentPose * rollRotation;
            q = app.KR6.model.ikine(newPose);
            app.KR6.model.animate(q);
        end

        % Button pushed function: YButton_4
        function YButton_4Pushed(app, event)
            currentPose = app.KR6.model.fkine(app.KR6.model.getpos()).T;
            rollAngle = deg2rad(app.deltaRotation);
            rollRotation = trotz(rollAngle);
            newPose = currentPose * rollRotation;
            q = app.KR6.model.ikine(newPose);
            app.KR6.model.animate(q);
        end

        % Button pushed function: DummyanimationButton
        function DummyanimationButtonPushed(app, event)
            %Simple dummy movement
            while(app.flag)
                q1 = zeros(1,app.KR6.model.n); %random joints to move between
                q2 = deg2rad([45, -20, 45, -30, -70,90]);
                
                firstMove = jtraj(q1,q2,app.steps);
                for i = 1:app.steps
                    app.KR6.model.animate(firstMove(i,:))
                    drawnow();
                    app.PrintFKine(app.KR6.model.fkineUTS(app.KR6.model.getpos));
                    if(~app.flag)
                        pause();
                    end
                end
                %reversing the movement
                backMove = jtraj(q2,q1,app.steps);
                for i = 1:app.steps
                    app.KR6.model.animate(backMove(i,:));
                    drawnow();
                    app.PrintFKine(app.KR6.model.fkineUTS(app.KR6.model.getpos));
                    if(~app.flag) %emergency button switches flag to pause, still can use jogging
                        pause();
                    end
                end
                % if(app.flag)
                %     break;
                % end
            end
        end

        % Button pushed function: EmergencyStopButton
        function EmergencyStopButtonPushed(app, event)
            %emergency button changes flag, no need for if statement
            %keeping just in case
            
            app.flag = ~app.flag;
            if(app.flag)
                % app.Lamp.Color = 'g';
                % app.DummyanimationButton.Enable = true;
                % app.HomeRobotButton.Enable = true;
            else %if pushed, change lamp colour and disable animations.
                app.Lamp.Color = 'r';
                app.DummyanimationButton.Enable = false;
                app.HomeRobotButton.Enable = false;
            end
            app.EmergencyStopButton.Enable = false;
            % app.HomeCheck(app.KR6.model.getpos);


        end

        % Button pushed function: EXITButton
        function EXITButtonPushed(app, event)
            app.delete;
        end

        % Button pushed function: HomeRobotButton
        function HomeRobotButtonPushed(app, event)
            %returns to zero position after reset.
            
            q1 = app.KR6.model.getpos();
            q2 = zeros(1,app.KR6.model.n);
            returnMatrix = jtraj(q1,q2,app.steps);
            for i = 1:app.steps
                app.KR6.model.animate(returnMatrix(i,:));
                drawnow();
                app.PrintFKine(app.KR6.model.fkineUTS(app.KR6.model.getpos));
            end
            %enables movement after homing is complete
            app.DummyanimationButton.Enable = true;
        end

        % Value changed function: PasswordEditField
        function PasswordEditFieldValueChanged(app, event)
            %password required after pressing emergency stop
            input = app.PasswordEditField.Value;
            %correct password enables home position
            if strcmp(input, app.password)
                app.Lamp.Color = 'g';
                app.flag = true;
                %app.DummyanimationButton.Enable = true;
                app.HomeRobotButton.Enable = true;
                app.EmergencyStopButton.Enable = true;
                app.PasswordEditField.Value = '';
            else
                
                app.DummyanimationButton.Enable = false;
                app.EmergencyStopButton.Enable = false;
                app.PasswordEditField.Value = '';
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'TestRobotPlot_V2';
            app.figure1.Tag = 'figure1';
            app.plot = uiaxes(app.figure1);
            xlabel(app.plot, 'X')
            ylabel(app.plot, 'Y')
            app.plot.Tag = 'axes1';
            app.XButton = uibutton(app.figure1, 'push');
            app.XButton.ButtonPushedFcn = createCallbackFcn(app, @XButtonPushed, true);
            app.XButton.Text = '+X';
            app.XButton_2 = uibutton(app.figure1, 'push');
            app.XButton_2.ButtonPushedFcn = createCallbackFcn(app, @XButton_2Pushed, true);
            app.XButton_2.Text = '-X';
            app.YButton = uibutton(app.figure1, 'push');
            app.YButton.ButtonPushedFcn = createCallbackFcn(app, @YButtonPushed, true);
            app.YButton.Text = '+ Y';
            app.YButton_2 = uibutton(app.figure1, 'push');
            app.YButton_2.ButtonPushedFcn = createCallbackFcn(app, @YButton_2Pushed, true);
            app.YButton_2.Text = '- Y';
            app.ZButton = uibutton(app.figure1, 'push');
            app.ZButton.ButtonPushedFcn = createCallbackFcn(app, @ZButtonPushed, true);
            app.ZButton.Text = '+ Z';
            app.ZButton_2 = uibutton(app.figure1, 'push');
            app.ZButton_2.ButtonPushedFcn = createCallbackFcn(app, @ZButton_2Pushed, true);
            app.ZButton_2.Text = '- Z';
            app.XLabel = uilabel(app.figure1);
            app.XLabel.Text = 'X:';
            app.YLabel = uilabel(app.figure1);
            app.YLabel.Text = 'Y:';
            app.ZLabel = uilabel(app.figure1);
            app.ZLabel.Text = 'Z:';
            app.EndEffectorGlobalPoseLabel = uilabel(app.figure1);
            app.EndEffectorGlobalPoseLabel.Text = 'End Effector Global Pose:';
            app.Label = uilabel(app.figure1);
            app.Label.Text = '';
            app.Label_2 = uilabel(app.figure1);
            app.Label_2.Text = '';
            app.Label_3 = uilabel(app.figure1);
            app.Label_3.Text = '';
            app.EndEffectorTranslationLabel = uilabel(app.figure1);
            app.EndEffectorTranslationLabel.Text = 'End Effector Translation';
            app.EndEffectorRotationLabel = uilabel(app.figure1);
            app.EndEffectorRotationLabel.Text = 'End Effector Rotation';
            app.RButton = uibutton(app.figure1, 'push');
            app.RButton.ButtonPushedFcn = createCallbackFcn(app, @RButtonPushed, true);
            app.RButton.Text = '- R';
            app.RButton_2 = uibutton(app.figure1, 'push');
            app.RButton_2.ButtonPushedFcn = createCallbackFcn(app, @RButton_2Pushed, true);
            app.RButton_2.Text = '+ R';
            app.PButton = uibutton(app.figure1, 'push');
            app.PButton.ButtonPushedFcn = createCallbackFcn(app, @PButtonPushed, true);
            app.PButton.Text = '- P';
            app.PButton_2 = uibutton(app.figure1, 'push');
            app.PButton_2.ButtonPushedFcn = createCallbackFcn(app, @PButton_2Pushed, true);
            app.PButton_2.Text = '+ P';
            app.YButton_3 = uibutton(app.figure1, 'push');
            app.YButton_3.ButtonPushedFcn = createCallbackFcn(app, @YButton_3Pushed, true);
            app.YButton_3.Text = '- Y';
            app.YButton_4 = uibutton(app.figure1, 'push');
            app.YButton_4.ButtonPushedFcn = createCallbackFcn(app, @YButton_4Pushed, true);
            app.YButton_4.Text = '+ Y';
            app.EmergencyStopButton = uibutton(app.figure1, 'push');
            app.EmergencyStopButton.ButtonPushedFcn = createCallbackFcn(app, @EmergencyStopButtonPushed, true);
            app.EmergencyStopButton.Text = 'Emergency Stop';
            app.Lamp = uilamp(app.figure1);
            app.DummyanimationButton = uibutton(app.figure1, 'push');
            app.DummyanimationButton.ButtonPushedFcn = createCallbackFcn(app, @DummyanimationButtonPushed, true);
            app.DummyanimationButton.Text = 'Dummy animation';
            app.EXITButton = uibutton(app.figure1, 'push');
            app.EXITButton.ButtonPushedFcn = createCallbackFcn(app, @EXITButtonPushed, true);
            app.EXITButton.Text = 'EXIT';
            app.HomeRobotButton = uibutton(app.figure1, 'push');
            app.HomeRobotButton.ButtonPushedFcn = createCallbackFcn(app, @HomeRobotButtonPushed, true);
            app.HomeRobotButton.Text = 'Home Robot';
            app.PasswordEditFieldLabel = uilabel(app.figure1);
            app.PasswordEditFieldLabel.Text = 'Password';
            app.PasswordEditField = uieditfield(app.figure1, 'text');
            app.PasswordEditField.ValueChangedFcn = createCallbackFcn(app, @PasswordEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = A2GUI(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)init(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end