classdef AppDesignerTest < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        TestingloadingButton  matlab.ui.control.Button
    end


    properties (Access = private)
        R900 % r.model saved to property "R900"
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: TestingloadingButton
        function TestingloadingButtonPushed(app, event)
            clf;
            r = KUKAKR6R900;
            app.R900 = r.model;
            assignin('base', 'R900','app.R900');
            T2 = transl(1.2,-1,0.2);
            j1 = zeros(1,r.model.n);
            j2 = r.model.ikcon(T2,r.model.getpos);

            qMatrix = jtraj(j1,j2,250);
            input('bruh');
            for i = 1:250
                r.model.animate(qMatrix(i,:));
                drawnow();
            end
            pause()
            

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TestingloadingButton = uibutton(app.UIFigure, 'push');
            app.TestingloadingButton.ButtonPushedFcn = createCallbackFcn(app, @TestingloadingButtonPushed, true);
            app.TestingloadingButton.Text = {'Testing'; ' loading'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AppDesignerTest

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end