classdef testRobotPlotApp_V2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                       matlab.ui.Figure
        EmergencyStopButton           matlab.ui.control.Button
        Label_6                       matlab.ui.control.Label
        Label_5                       matlab.ui.control.Label
        Label_4                       matlab.ui.control.Label
        EndEffectorGlobalPoseLabel_2  matlab.ui.control.Label
        YLabel_2                      matlab.ui.control.Label
        PLabel                        matlab.ui.control.Label
        RLabel                        matlab.ui.control.Label
        YButton_4                     matlab.ui.control.Button
        YButton_3                     matlab.ui.control.Button
        PButton_2                     matlab.ui.control.Button
        PButton                       matlab.ui.control.Button
        RButton_2                     matlab.ui.control.Button
        RButton                       matlab.ui.control.Button
        EndEffectorRotationLabel      matlab.ui.control.Label
        EndEffectorTranslationLabel   matlab.ui.control.Label
        Label_3                       matlab.ui.control.Label
        Label_2                       matlab.ui.control.Label
        Label                         matlab.ui.control.Label
        EndEffectorGlobalPoseLabel    matlab.ui.control.Label
        ZLabel                        matlab.ui.control.Label
        YLabel                        matlab.ui.control.Label
        XLabel                        matlab.ui.control.Label
        ZButton_2                     matlab.ui.control.Button
        ZButton                       matlab.ui.control.Button
        YButton_2                     matlab.ui.control.Button
        YButton                       matlab.ui.control.Button
        XButton_2                     matlab.ui.control.Button
        XButton                       matlab.ui.control.Button
        plot                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        % place your class properties here
        modelPuma % Where I will put the loaded puma
        KR6 % Where I will put the loaded UR5
        qz = [0     0     0     0     0     0];
        qrPuma = [0	pi/2  -pi/2	0	0	0];
        qrUR5 = [pi/4	pi/2  -pi/2	0	0	0];
        deltaTranslation = 0.01;
        deltaRotation = 6;
    end
    
    methods (Access = private)
        
        function PrintFKine(app, newPose)
            xPos = newPose(1, 4);
            yPos = newPose(2, 4);
            zPos = newPose(3, 4);
            app.Label.Text = num2str(xPos);
            app.Label_2.Text = num2str(yPos);
            app.Label_3.Text = num2str(zPos);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function init(app, varargin)
            % Code to run once GUI is loaded successfully
            ax = gca;

            % Make, save and plot some robots            
            app.KR6 = KUKAKR6R900;
            %app.KR6.model.base = transl([-0.5,0,0]);
            app.KR6.model.animate(zeros(1,app.KR6.model.n));
            drawnow();

            % Delete the green floor surface (need to count backwards
            % because once it is deleted the array is compressed)
            for i = size(ax.Children,1):-1:1
                if strcmp(ax.Children(i).Type,'surface')
                    delete(ax.Children(i));
                end
            end
            
            % Turn off this load button and enable the move button
            
        end

        % Button pushed function: XButton_2
        function XButton_2Pushed(app, event)
            % Move the end effector in the negative X direction
            currentPose = app.KR6.model.fkine(app.KR6.model.getpos()).T;
            newPose = currentPose * transl(-app.deltaTranslation, 0, 0);
            q = app.KR6.model.ikine(newPose);
            app.KR6.model.animate(q);
            app.PrintFKine(newPose);
        end

        % Button pushed function: XButton
        function XButtonPushed(app, event)
            % Move the end effector in the negative X direction
            currentPose = app.KR6.model.fkine(app.KR6.model.getpos()).T;
            newPose = currentPose * transl(app.deltaTranslation, 0, 0);
            q = app.KR6.model.ikine(newPose);
            app.KR6.model.animate(q);
            app.PrintFKine(newPose);            
        end

        % Button pushed function: YButton_2
        function YButton_2Pushed(app, event)
            % Move the end effector in the negative X direction
            currentPose = app.KR6.model.fkine(app.KR6.model.getpos()).T;
            newPose = currentPose * transl(0, -app.deltaTranslation, 0);
            q = app.KR6.model.ikine(newPose);
            app.KR6.model.animate(q);
            app.PrintFKine(newPose);
        end

        % Button pushed function: YButton
        function YButtonPushed(app, event)
            % Move the end effector in the negative X direction
            currentPose = app.KR6.model.fkine(app.KR6.model.getpos()).T;
            newPose = currentPose * transl(0, app.deltaTranslation, 0);
            q = app.KR6.model.ikine(newPose);
            app.KR6.model.animate(q);
            app.PrintFKine(newPose);            
        end

        % Button pushed function: ZButton_2
        function ZButton_2Pushed(app, event)
             % Move the end effector in the negative X direction
            currentPose = app.KR6.model.fkine(app.KR6.model.getpos()).T;
            newPose = currentPose * transl(0, 0, -app.deltaTranslation);
            q = app.KR6.model.ikine(newPose);
            app.KR6.model.animate(q);
            app.PrintFKine(newPose);           
        end

        % Button pushed function: ZButton
        function ZButtonPushed(app, event)
            % Move the end effector in the negative X direction
            currentPose = app.KR6.model.fkine(app.KR6.model.getpos()).T;
            newPose = currentPose * transl(0, 0, app.deltaTranslation);
            q = app.KR6.model.ikine(newPose);
            app.KR6.model.animate(q);
            app.PrintFKine(newPose);            
        end

        % Button pushed function: RButton
        function RButtonPushed(app, event)
            currentPose = app.KR6.model.fkine(app.KR6.model.getpos()).T;
            rollAngle = deg2rad(app.deltaRotation);
            rollRotation = trotx(-rollAngle);
            newPose = currentPose * rollRotation;
            q = app.KR6.model.ikine(newPose);
            app.KR6.model.animate(q);
        end

        % Button pushed function: RButton_2
        function RButton_2Pushed(app, event)
            currentPose = app.KR6.model.fkine(app.KR6.model.getpos()).T;
            rollAngle = deg2rad(app.deltaRotation);
            rollRotation = trotx(rollAngle);  
            newPose = currentPose * rollRotation;
            q = app.KR6.model.ikine(newPose);
            app.KR6.model.animate(q);
        end

        % Button pushed function: PButton
        function PButtonPushed(app, event)
            currentPose = app.KR6.model.fkine(app.KR6.model.getpos()).T;
            rollAngle = deg2rad(app.deltaRotation);
            rollRotation = troty(-rollAngle);  % Create a roll rotation matrix
            newPose = currentPose * rollRotation;
            q = app.KR6.model.ikine(newPose);
            app.KR6.model.animate(q);
        end

        % Button pushed function: PButton_2
        function PButton_2Pushed(app, event)
            currentPose = app.KR6.model.fkine(app.KR6.model.getpos()).T;
            rollAngle = deg2rad(app.deltaRotation);
            rollRotation = troty(rollAngle);  % Create a roll rotation matrix
            newPose = currentPose * rollRotation;
            q = app.KR6.model.ikine(newPose);
            app.KR6.model.animate(q);
        end

        % Button pushed function: YButton_3
        function YButton_3Pushed(app, event)
            currentPose = app.KR6.model.fkine(app.KR6.model.getpos()).T;
            rollAngle = deg2rad(app.deltaRotation);
            rollRotation = trotz(-rollAngle);  % Create a roll rotation matrix
            newPose = currentPose * rollRotation;
            q = app.KR6.model.ikine(newPose);
            app.KR6.model.animate(q);
        end

        % Button pushed function: YButton_4
        function YButton_4Pushed(app, event)
            currentPose = app.KR6.model.fkine(app.KR6.model.getpos()).T;
            rollAngle = deg2rad(app.deltaRotation);
            rollRotation = trotz(rollAngle);  % Create a roll rotation matrix
            newPose = currentPose * rollRotation;
            q = app.KR6.model.ikine(newPose);
            app.KR6.model.animate(q);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'TestRobotPlot_V2';
            app.figure1.Tag = 'figure1';
            app.plot = uiaxes(app.figure1);
            xlabel(app.plot, 'X')
            ylabel(app.plot, 'Y')
            app.plot.Tag = 'axes1';
            app.XButton = uibutton(app.figure1, 'push');
            app.XButton.ButtonPushedFcn = createCallbackFcn(app, @XButtonPushed, true);
            app.XButton.Text = '+X';
            app.XButton_2 = uibutton(app.figure1, 'push');
            app.XButton_2.ButtonPushedFcn = createCallbackFcn(app, @XButton_2Pushed, true);
            app.XButton_2.Text = '-X';
            app.YButton = uibutton(app.figure1, 'push');
            app.YButton.ButtonPushedFcn = createCallbackFcn(app, @YButtonPushed, true);
            app.YButton.Text = '+ Y';
            app.YButton_2 = uibutton(app.figure1, 'push');
            app.YButton_2.ButtonPushedFcn = createCallbackFcn(app, @YButton_2Pushed, true);
            app.YButton_2.Text = '- Y';
            app.ZButton = uibutton(app.figure1, 'push');
            app.ZButton.ButtonPushedFcn = createCallbackFcn(app, @ZButtonPushed, true);
            app.ZButton.Text = '+ Z';
            app.ZButton_2 = uibutton(app.figure1, 'push');
            app.ZButton_2.ButtonPushedFcn = createCallbackFcn(app, @ZButton_2Pushed, true);
            app.ZButton_2.Text = '- Z';
            app.XLabel = uilabel(app.figure1);
            app.XLabel.Text = 'X:';
            app.YLabel = uilabel(app.figure1);
            app.YLabel.Text = 'Y:';
            app.ZLabel = uilabel(app.figure1);
            app.ZLabel.Text = 'Z:';
            app.EndEffectorGlobalPoseLabel = uilabel(app.figure1);
            app.EndEffectorGlobalPoseLabel.Text = 'End Effector Global Pose:';
            app.Label = uilabel(app.figure1);
            app.Label.Text = '';
            app.Label_2 = uilabel(app.figure1);
            app.Label_2.Text = '';
            app.Label_3 = uilabel(app.figure1);
            app.Label_3.Text = '';
            app.EndEffectorTranslationLabel = uilabel(app.figure1);
            app.EndEffectorTranslationLabel.Text = 'End Effector Translation';
            app.EndEffectorRotationLabel = uilabel(app.figure1);
            app.EndEffectorRotationLabel.Text = 'End Effector Rotation';
            app.RButton = uibutton(app.figure1, 'push');
            app.RButton.ButtonPushedFcn = createCallbackFcn(app, @RButtonPushed, true);
            app.RButton.Text = '- R';
            app.RButton_2 = uibutton(app.figure1, 'push');
            app.RButton_2.ButtonPushedFcn = createCallbackFcn(app, @RButton_2Pushed, true);
            app.RButton_2.Text = '+ R';
            app.PButton = uibutton(app.figure1, 'push');
            app.PButton.ButtonPushedFcn = createCallbackFcn(app, @PButtonPushed, true);
            app.PButton.Text = '- P';
            app.PButton_2 = uibutton(app.figure1, 'push');
            app.PButton_2.ButtonPushedFcn = createCallbackFcn(app, @PButton_2Pushed, true);
            app.PButton_2.Text = '+ P';
            app.YButton_3 = uibutton(app.figure1, 'push');
            app.YButton_3.ButtonPushedFcn = createCallbackFcn(app, @YButton_3Pushed, true);
            app.YButton_3.Text = '- Y';
            app.YButton_4 = uibutton(app.figure1, 'push');
            app.YButton_4.ButtonPushedFcn = createCallbackFcn(app, @YButton_4Pushed, true);
            app.YButton_4.Text = '+ Y';
            app.RLabel = uilabel(app.figure1);
            app.RLabel.Text = 'R:';
            app.PLabel = uilabel(app.figure1);
            app.PLabel.Text = 'P:';
            app.YLabel_2 = uilabel(app.figure1);
            app.YLabel_2.Text = 'Y:';
            app.EndEffectorGlobalPoseLabel_2 = uilabel(app.figure1);
            app.EndEffectorGlobalPoseLabel_2.Text = 'End Effector Global Pose:';
            app.Label_4 = uilabel(app.figure1);
            app.Label_4.Text = '';
            app.Label_5 = uilabel(app.figure1);
            app.Label_5.Text = '';
            app.Label_6 = uilabel(app.figure1);
            app.Label_6.Text = '';
            app.EmergencyStopButton = uibutton(app.figure1, 'push');
            app.EmergencyStopButton.Text = 'Emergency Stop';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = testRobotPlotApp_V2(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)init(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end