classdef joggingMovementApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        EmergencyStopButton           matlab.ui.control.Button
        Label_6                       matlab.ui.control.Label
        Label_5                       matlab.ui.control.Label
        Label_4                       matlab.ui.control.Label
        EndEffectorGlobalPoseLabel_2  matlab.ui.control.Label
        YLabel_2                      matlab.ui.control.Label
        PLabel                        matlab.ui.control.Label
        RLabel                        matlab.ui.control.Label
        YButton_4                     matlab.ui.control.Button
        YButton_3                     matlab.ui.control.Button
        PButton_2                     matlab.ui.control.Button
        PButton                       matlab.ui.control.Button
        RButton_2                     matlab.ui.control.Button
        RButton                       matlab.ui.control.Button
        EndEffectorRotationLabel      matlab.ui.control.Label
        EndEffectorTranslationLabel   matlab.ui.control.Label
        Label_3                       matlab.ui.control.Label
        Label_2                       matlab.ui.control.Label
        Label                         matlab.ui.control.Label
        EndEffectorGlobalPoseLabel    matlab.ui.control.Label
        ZLabel                        matlab.ui.control.Label
        YLabel                        matlab.ui.control.Label
        XLabel                        matlab.ui.control.Label
        ZButton                       matlab.ui.control.Button
        ZButton_2                     matlab.ui.control.Button
        LoadRobotResetButton          matlab.ui.control.Button
        YButton                       matlab.ui.control.Button
        YButton_2                     matlab.ui.control.Button
        XButton                       matlab.ui.control.Button
        XButton_2                     matlab.ui.control.Button
        plot                          matlab.ui.control.UIAxes
    end


    properties (Access = private)
        KR6;
        deltaTranslation = 0.01;
        deltaRotation = 6;
    end

    methods (Access = private)
        function PrintFKine(app, newPose)
            xPos = newPose(1, 4);
            yPos = newPose(2, 4);
            zPos = newPose(3, 4);
            app.Label.Text = num2str(xPos);
            app.Label_2.Text = num2str(yPos);
            app.Label_3.Text = num2str(zPos);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: XButton_2
        function XButton_2Pushed(app, event)
            % Move the end effector in the positive X direction
            currentPose = app.KR6.fkine(app.KR6.getpos()).T;
            translation = transl(app.deltaTranslation, 0, 0);
            newPose = currentPose * translation;
            q = app.KR6.ikine(newPose);
            app.KR6.animate(q);
            app.PrintFKine(newPose);
        end

        % Button pushed function: YButton_2
        function YButton_2Pushed(app, event)
            % Move the end effector in the positive Y direction
            currentPose = app.KR6.fkine(app.KR6.getpos()).T;
            newPose = currentPose * transl(0, app.deltaTranslation, 0);
            q = app.KR6.ikine(newPose);
            app.KR6.animate(q);
            app.PrintFKine(newPose);
        end

        % Button pushed function: XButton
        function XButtonPushed(app, event)
            % Move the end effector in the negative X direction
            currentPose = app.KR6.fkine(app.KR6.getpos()).T;
            newPose = currentPose * transl(-app.deltaTranslation, 0, 0);
            q = app.KR6.ikine(newPose);
            app.KR6.animate(q);
            app.PrintFKine(newPose);
        end

        % Button pushed function: YButton
        function YButtonPushed(app, event)
            % Move the end effector in the negative Y direction
            currentPose = app.KR6.fkine(app.KR6.getpos()).T;
            newPose = currentPose * transl(0, -app.deltaTranslation, 0);
            q = app.KR6.ikine(newPose);
            app.KR6.animate(q);
            app.PrintFKine(newPose);

        end

        % Button pushed function: LoadRobotResetButton
        function LoadRobotResetButtonPushed(app, event)
            clf;
            r = KUKAKR6R900;
            app.KR6 = r.model;
            view(3)
            pause();
        end

        % Button pushed function: ZButton_2
        function ZButton_2Pushed(app, event)
            currentPose = app.KR6.fkine(app.KR6.getpos()).T;
            newPose = currentPose * transl(0, 0, app.deltaTranslation);
            q = app.KR6.ikine(newPose);
            app.KR6.animate(q);
            app.PrintFKine(newPose);
        end

        % Button pushed function: ZButton
        function ZButtonPushed(app, event)
            currentPose = app.KR6.fkine(app.KR6.getpos()).T;
            newPose = currentPose * transl(0, 0, -app.deltaTranslation);
            q = app.KR6.ikine(newPose);
            app.KR6.animate(q);
            app.PrintFKine(newPose);
        end

        % Button pushed function: RButton
        function RButtonPushed(app, event)
            currentPose = app.KR6.fkine(app.KR6.getpos()).T;
            rollAngle = deg2rad(app.deltaRotation);
            rollRotation = trotx(-rollAngle);  % Create a roll rotation matrix
            newPose = currentPose * rollRotation;
            q = app.KR6.ikine(newPose);
            app.KR6.animate(q);
        end

        % Button pushed function: RButton_2
        function RButton_2Pushed(app, event)
            currentPose = app.KR6.fkine(app.KR6.getpos()).T;
            rollAngle = deg2rad(app.deltaRotation);
            rollRotation = trotx(rollAngle);
            newPose = currentPose * rollRotation;
            q = app.KR6.ikine(newPose);
            app.KR6.animate(q);
        end

        % Button pushed function: PButton
        function PButtonPushed(app, event)
            currentPose = app.KR6.fkine(app.KR6.getpos()).T;
            rollAngle = deg2rad(app.deltaRotation);
            rollRotation = troty(-rollAngle);
            newPose = currentPose * rollRotation;
            q = app.KR6.ikine(newPose);
            app.KR6.animate(q);
        end

        % Button pushed function: PButton_2
        function PButton_2Pushed(app, event)
            currentPose = app.KR6.fkine(app.KR6.getpos()).T;
            rollAngle = deg2rad(app.deltaRotation);
            rollRotation = troty(rollAngle);
            newPose = currentPose * rollRotation;
            q = app.KR6.ikine(newPose);
            app.KR6.animate(q);
        end

        % Button pushed function: YButton_3
        function YButton_3Pushed(app, event)
            currentPose = app.KR6.fkine(app.KR6.getpos()).T;
            rollAngle = deg2rad(app.deltaRotation);
            rollRotation = trotz(-rollAngle);
            newPose = currentPose * rollRotation;
            q = app.KR6.ikine(newPose);
            app.KR6.animate(q);
        end

        % Button pushed function: YButton_4
        function YButton_4Pushed(app, event)
            currentPose = app.KR6.fkine(app.KR6.getpos()).T;
            rollAngle = deg2rad(app.deltaRotation);
            rollRotation = trotz(rollAngle);
            newPose = currentPose * rollRotation;
            q = app.KR6.ikine(newPose);
            app.KR6.animate(q);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.plot = uiaxes(app.UIFigure);
            xlabel(app.plot, 'X')
            ylabel(app.plot, 'Y')
            app.plot.Tag = 'axes1';
            app.XButton_2 = uibutton(app.UIFigure, 'push');
            app.XButton_2.ButtonPushedFcn = createCallbackFcn(app, @XButton_2Pushed, true);
            app.XButton_2.Text = '+X';
            app.XButton = uibutton(app.UIFigure, 'push');
            app.XButton.ButtonPushedFcn = createCallbackFcn(app, @XButtonPushed, true);
            app.XButton.Text = '-X';
            app.YButton_2 = uibutton(app.UIFigure, 'push');
            app.YButton_2.ButtonPushedFcn = createCallbackFcn(app, @YButton_2Pushed, true);
            app.YButton_2.Text = '+ Y';
            app.YButton = uibutton(app.UIFigure, 'push');
            app.YButton.ButtonPushedFcn = createCallbackFcn(app, @YButtonPushed, true);
            app.YButton.Text = '- Y';
            app.LoadRobotResetButton = uibutton(app.UIFigure, 'push');
            app.LoadRobotResetButton.ButtonPushedFcn = createCallbackFcn(app, @LoadRobotResetButtonPushed, true);
            app.LoadRobotResetButton.Text = 'Load Robot/ Reset';
            app.ZButton_2 = uibutton(app.UIFigure, 'push');
            app.ZButton_2.ButtonPushedFcn = createCallbackFcn(app, @ZButton_2Pushed, true);
            app.ZButton_2.Text = '+ Z';
            app.ZButton = uibutton(app.UIFigure, 'push');
            app.ZButton.ButtonPushedFcn = createCallbackFcn(app, @ZButtonPushed, true);
            app.ZButton.Text = '- Z';
            app.XLabel = uilabel(app.UIFigure);
            app.XLabel.Text = 'X:';
            app.YLabel = uilabel(app.UIFigure);
            app.YLabel.Text = 'Y:';
            app.ZLabel = uilabel(app.UIFigure);
            app.ZLabel.Text = 'Z:';
            app.EndEffectorGlobalPoseLabel = uilabel(app.UIFigure);
            app.EndEffectorGlobalPoseLabel.Text = 'End Effector Global Pose:';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '';
            app.EndEffectorTranslationLabel = uilabel(app.UIFigure);
            app.EndEffectorTranslationLabel.Text = 'End Effector Translation';
            app.EndEffectorRotationLabel = uilabel(app.UIFigure);
            app.EndEffectorRotationLabel.Text = 'End Effector Rotation';
            app.RButton = uibutton(app.UIFigure, 'push');
            app.RButton.ButtonPushedFcn = createCallbackFcn(app, @RButtonPushed, true);
            app.RButton.Text = '- R';
            app.RButton_2 = uibutton(app.UIFigure, 'push');
            app.RButton_2.ButtonPushedFcn = createCallbackFcn(app, @RButton_2Pushed, true);
            app.RButton_2.Text = '+ R';
            app.PButton = uibutton(app.UIFigure, 'push');
            app.PButton.ButtonPushedFcn = createCallbackFcn(app, @PButtonPushed, true);
            app.PButton.Text = '- P';
            app.PButton_2 = uibutton(app.UIFigure, 'push');
            app.PButton_2.ButtonPushedFcn = createCallbackFcn(app, @PButton_2Pushed, true);
            app.PButton_2.Text = '+ P';
            app.YButton_3 = uibutton(app.UIFigure, 'push');
            app.YButton_3.ButtonPushedFcn = createCallbackFcn(app, @YButton_3Pushed, true);
            app.YButton_3.Text = '- Y';
            app.YButton_4 = uibutton(app.UIFigure, 'push');
            app.YButton_4.ButtonPushedFcn = createCallbackFcn(app, @YButton_4Pushed, true);
            app.YButton_4.Text = '+ Y';
            app.RLabel = uilabel(app.UIFigure);
            app.RLabel.Text = 'R:';
            app.PLabel = uilabel(app.UIFigure);
            app.PLabel.Text = 'P:';
            app.YLabel_2 = uilabel(app.UIFigure);
            app.YLabel_2.Text = 'Y:';
            app.EndEffectorGlobalPoseLabel_2 = uilabel(app.UIFigure);
            app.EndEffectorGlobalPoseLabel_2.Text = 'End Effector Global Pose:';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '';
            app.EmergencyStopButton = uibutton(app.UIFigure, 'push');
            app.EmergencyStopButton.Text = 'Emergency Stop';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = joggingMovementApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end